classdef APAS_TR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputsMenu                      matlab.ui.container.Menu
        RinexDataMenu                   matlab.ui.container.Menu
        OrbitandClockProductsMenu       matlab.ui.container.Menu
        AntennaandDCBFilesMenu          matlab.ui.container.Menu
        ModelsMenu                      matlab.ui.container.Menu
        SatelliteandReceiverDependentMenu  matlab.ui.container.Menu
        AtmosphereMenu                  matlab.ui.container.Menu
        FilteringMenu                   matlab.ui.container.Menu
        AdjustmentSettingsMenu          matlab.ui.container.Menu
        OutputsMenu                     matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        PlottingMenu                    matlab.ui.container.Menu
        RUNButton                       matlab.ui.control.Button
        StatusTextArea                  matlab.ui.control.TextArea
        StatusLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RinexTab                        matlab.ui.container.Tab
        QZSSLabel                       matlab.ui.control.Label
        BDS3Label                       matlab.ui.control.Label
        BDS2Label                       matlab.ui.control.Label
        GalileoLabel                    matlab.ui.control.Label
        GLONASSLabel                    matlab.ui.control.Label
        GPSLabel                        matlab.ui.control.Label
        GNSSObservationRinexDataLabel   matlab.ui.control.Label
        PPPCombinationsPanel            matlab.ui.container.Panel
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        GREC2C3Button                   matlab.ui.control.RadioButton
        GREC2C3JButton                  matlab.ui.control.RadioButton
        GREC3Button                     matlab.ui.control.RadioButton
        GREC2Button                     matlab.ui.control.RadioButton
        GREButton                       matlab.ui.control.RadioButton
        GEButton                        matlab.ui.control.RadioButton
        GRButton                        matlab.ui.control.RadioButton
        GButton                         matlab.ui.control.RadioButton
        EF_J                            matlab.ui.control.EditField
        EF_C3                           matlab.ui.control.EditField
        EF_C2                           matlab.ui.control.EditField
        EF_E                            matlab.ui.control.EditField
        EF_R                            matlab.ui.control.EditField
        EF_G                            matlab.ui.control.EditField
        SignalTypesLabel                matlab.ui.control.Label
        RinexFileButton                 matlab.ui.control.Button
        RinexFileEditField              matlab.ui.control.EditField
        RinexFileEditFieldLabel         matlab.ui.control.Label
        OrbClkTab                       matlab.ui.container.Tab
        SatelliteClockCLKProductLabel   matlab.ui.control.Label
        SatelliteOrbitSP3ProductLabel   matlab.ui.control.Label
        ClockFileButton                 matlab.ui.control.Button
        ClockFileEditField              matlab.ui.control.EditField
        ClockFileEditFieldLabel         matlab.ui.control.Label
        OrbitFileButton                 matlab.ui.control.Button
        OrbitFileEditField              matlab.ui.control.EditField
        OrbitFileEditFieldLabel         matlab.ui.control.Label
        AntexTab                        matlab.ui.container.Tab
        DCBFileEditField                matlab.ui.control.EditField
        DCBFileEditFieldLabel           matlab.ui.control.Label
        DifferentialCodeBiasDCBOptionalLabel  matlab.ui.control.Label
        AntennaATXLabel                 matlab.ui.control.Label
        DCBFileButton                   matlab.ui.control.Button
        AntexFileButton                 matlab.ui.control.Button
        AntennaFileEditField            matlab.ui.control.EditField
        AntennaFileEditFieldLabel       matlab.ui.control.Label
        M1Tab                           matlab.ui.container.Tab
        dBHzLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UseSmoothCodeMeasurementsCheckBox  matlab.ui.control.CheckBox
        SatelliteCN0CutOffEditField     matlab.ui.control.NumericEditField
        SatelliteCN0CutOffLabel         matlab.ui.control.Label
        CycleSlipDetectionAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        TurboEditAlgorithmButton        matlab.ui.control.RadioButton
        ROTIbasedGFCombinationFBMWAMWCombinationButton  matlab.ui.control.RadioButton
        SatelliteElevationCutOffEditField  matlab.ui.control.NumericEditField
        SatelliteElevationCutOffLabel   matlab.ui.control.Label
        SolidTideCheckBox               matlab.ui.control.CheckBox
        ReceiverClockJumpDetectionCheckBox  matlab.ui.control.CheckBox
        ReceiverAntennaReferencePointCheckBox  matlab.ui.control.CheckBox
        SatelliteEclipseConditionCheckBox  matlab.ui.control.CheckBox
        ReceiverPhaseCenterVariationCheckBox  matlab.ui.control.CheckBox
        SatelliteWindupEffectCheckBox   matlab.ui.control.CheckBox
        ReceiverPhaseCenterOffsetCheckBox  matlab.ui.control.CheckBox
        SatellitePhaseCenterOffsetCheckBox  matlab.ui.control.CheckBox
        ReceiverDependentandOtherErrorSourcesPanel  matlab.ui.container.Panel
        SatelliteDependentErrorSourcesPanel  matlab.ui.container.Panel
        M2Tab                           matlab.ui.container.Tab
        TroposphericModelingPanel       matlab.ui.container.Panel
        ZenithDelayDryLabel             matlab.ui.control.Label
        VMF3Label_12                    matlab.ui.control.Label
        VMF1Label_12                    matlab.ui.control.Label
        GPT3SaastomienLabel             matlab.ui.control.Label
        GPT2SaastomienLabel             matlab.ui.control.Label
        UNB3Label_6                     matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        NMF                             matlab.ui.control.RadioButton
        GPT2                            matlab.ui.control.RadioButton
        GPT3                            matlab.ui.control.RadioButton
        VMF1                            matlab.ui.control.RadioButton
        VMF3                            matlab.ui.control.RadioButton
        UNB3Label_5                     matlab.ui.control.Label
        GPT2AskneLabel                  matlab.ui.control.Label
        GPT3AskneLabel                  matlab.ui.control.Label
        VMF1Label_11                    matlab.ui.control.Label
        VMF3Label_11                    matlab.ui.control.Label
        ZenithDelayWetLabel             matlab.ui.control.Label
        MappingFunctionDryLabel         matlab.ui.control.Label
        NMFLabel_6                      matlab.ui.control.Label
        VMF1GPT2Label_6                 matlab.ui.control.Label
        VMF3GPT3Label_6                 matlab.ui.control.Label
        VMF1Label_10                    matlab.ui.control.Label
        VMF3Label_10                    matlab.ui.control.Label
        NMFLabel_5                      matlab.ui.control.Label
        VMF1GPT2Label_5                 matlab.ui.control.Label
        VMF3GPT3Label_5                 matlab.ui.control.Label
        VMF1Label_9                     matlab.ui.control.Label
        VMF3Label_9                     matlab.ui.control.Label
        MappingFunctionWetLabel_3       matlab.ui.control.Label
        FilterTab                       matlab.ui.container.Tab
        SettingsofratiosforMultiGNSSPanel  matlab.ui.container.Panel
        C_mBDSMEOC_iBDSIGSOC_gBDSGEOLabel  matlab.ui.control.Label
        CiLabel                         matlab.ui.control.Label
        Phase_Cigso_weight              matlab.ui.control.NumericEditField
        Code_Cigso_weight               matlab.ui.control.NumericEditField
        JLabel                          matlab.ui.control.Label
        CgLabel                         matlab.ui.control.Label
        CmLabel                         matlab.ui.control.Label
        ELabel                          matlab.ui.control.Label
        RLabel                          matlab.ui.control.Label
        GLabel                          matlab.ui.control.Label
        PhaseEditFieldLabel_2           matlab.ui.control.Label
        CodeEditFieldLabel_2            matlab.ui.control.Label
        Phase_QZSS_weight               matlab.ui.control.NumericEditField
        Phase_Cgeo_weight               matlab.ui.control.NumericEditField
        Phase_Cmeo_weight               matlab.ui.control.NumericEditField
        Phase_GAL_weight                matlab.ui.control.NumericEditField
        Phase_GLO_weight                matlab.ui.control.NumericEditField
        Phase_GPS_weight                matlab.ui.control.NumericEditField
        Code_QZSS_weight                matlab.ui.control.NumericEditField
        Code_Cgeo_weight                matlab.ui.control.NumericEditField
        Code_Cmeo_weight                matlab.ui.control.NumericEditField
        Code_GAL_weight                 matlab.ui.control.NumericEditField
        Code_GLO_weight                 matlab.ui.control.NumericEditField
        Code_GPS_weight                 matlab.ui.control.NumericEditField
        StandardDeviationofmeasurementsPanel  matlab.ui.container.Panel
        mLabel_12                       matlab.ui.control.Label
        mLabel_11                       matlab.ui.control.Label
        PhaseEditField                  matlab.ui.control.NumericEditField
        PhaseLabel                      matlab.ui.control.Label
        CodeEditField                   matlab.ui.control.NumericEditField
        CodeLabel                       matlab.ui.control.Label
        WeightingOptionsPanel           matlab.ui.container.Panel
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        ElevationAngleCN0DependentButton  matlab.ui.control.RadioButton
        CN0DependentButton              matlab.ui.control.RadioButton
        ElevationAngleDependentButton   matlab.ui.control.RadioButton
        FilteringParametersPanel        matlab.ui.container.Panel
        mLabel_10                       matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        mLabel_8                        matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        mhLabel_5                       matlab.ui.control.Label
        mhLabel_4                       matlab.ui.control.Label
        mhLabel_3                       matlab.ui.control.Label
        mhLabel_2                       matlab.ui.control.Label
        mhLabel                         matlab.ui.control.Label
        SystemNoiseLabel                matlab.ui.control.Label
        InitialStandardDeviationLabel   matlab.ui.control.Label
        FloatAmbiguitiesEditField       matlab.ui.control.NumericEditField
        FloatAmbigutiesLabel            matlab.ui.control.Label
        FloatAmbiguitiesNoise           matlab.ui.control.NumericEditField
        InterSystemBiasesEditField      matlab.ui.control.NumericEditField
        InterSystemBiasesLabel          matlab.ui.control.Label
        InterSystemBiasesNoise          matlab.ui.control.NumericEditField
        ZenithWetDelayEditField         matlab.ui.control.NumericEditField
        ZenithWetDelayLabel_4           matlab.ui.control.Label
        ZenithWetDelayNoise             matlab.ui.control.NumericEditField
        GPSReceiverClockEditField       matlab.ui.control.NumericEditField
        GPSReceiverClockLabel           matlab.ui.control.Label
        GPSReceiverClockNoise           matlab.ui.control.NumericEditField
        CoordinatesNoise                matlab.ui.control.NumericEditField
        CoordinatesEditField            matlab.ui.control.NumericEditField
        CoordinatesLabel                matlab.ui.control.Label
        SolAnlysTab                     matlab.ui.container.Tab
        mLabel_16                       matlab.ui.control.Label
        mLabel_15                       matlab.ui.control.Label
        mLabel_14                       matlab.ui.control.Label
        minLabel                        matlab.ui.control.Label
        ZTDRMSEditField                 matlab.ui.control.NumericEditField
        ZTDRMSEditFieldLabel            matlab.ui.control.Label
        mLabel_13                       matlab.ui.control.Label
        GroundTruthZenithTroposphereDelayOptionalPanel  matlab.ui.container.Panel
        ZTDPathEditFieldLabel           matlab.ui.control.Label
        ZTDPathEditField                matlab.ui.control.EditField
        TropFileButton                  matlab.ui.control.Button
        mLabel_5                        matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        Combination                     matlab.ui.control.EditField
        CalculateButton                 matlab.ui.control.Button
        ConvTimeEditField               matlab.ui.control.NumericEditField
        ConvTimeEditFieldLabel          matlab.ui.control.Label
        DRMSEditField                   matlab.ui.control.NumericEditField
        DRMSEditFieldLabel              matlab.ui.control.Label
        UpRMSEditField                  matlab.ui.control.NumericEditField
        UpRMSEditFieldLabel             matlab.ui.control.Label
        EastRMSEditField                matlab.ui.control.NumericEditField
        EastRMSEditFieldLabel           matlab.ui.control.Label
        NorthRMSEditField               matlab.ui.control.NumericEditField
        NorthRMSEditFieldLabel          matlab.ui.control.Label
        ConvergenceCriteriaEditField    matlab.ui.control.NumericEditField
        ConvergenceCriteriaLabel        matlab.ui.control.Label
        GroundTruthCoordinatesButtonGroup  matlab.ui.container.ButtonGroup
        mLabel_18                       matlab.ui.control.Label
        mLabel_17                       matlab.ui.control.Label
        SinexPathEditFieldLabel         matlab.ui.control.Label
        SinexPathEditField              matlab.ui.control.EditField
        mLabel                          matlab.ui.control.Label
        fromRinexFileButton             matlab.ui.control.RadioButton
        SinexFileButton                 matlab.ui.control.Button
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        fromSinexFileButton             matlab.ui.control.RadioButton
        ManuallyButton                  matlab.ui.control.RadioButton
        PlotTab                         matlab.ui.container.Tab
        HorizontalErrorsButton          matlab.ui.control.Button
        SkyPlotAnalysisButton           matlab.ui.control.Button
        ResidualAnalysisPanel           matlab.ui.container.Panel
        ObservationListBox              matlab.ui.control.ListBox
        ObservationListBoxLabel         matlab.ui.control.Label
        PRNEditField                    matlab.ui.control.NumericEditField
        PRNEditFieldLabel               matlab.ui.control.Label
        SystemListBox                   matlab.ui.control.ListBox
        SystemListBoxLabel              matlab.ui.control.Label
        AcctoListBox                    matlab.ui.control.ListBox
        AcctoListBoxLabel               matlab.ui.control.Label
        PLOTButton                      matlab.ui.control.Button
        Residuals                       matlab.ui.container.ButtonGroup
        ResidualsforeachsatelliteButton  matlab.ui.control.RadioButton
        PhaseResidualsButton            matlab.ui.control.RadioButton
        CodeResidualsButton             matlab.ui.control.RadioButton
        GDOPandPDOPValuesButton         matlab.ui.control.Button
        CN0AnalysisButton               matlab.ui.control.Button
        SatelliteVisibilityChartButton  matlab.ui.control.Button
        SatelliteNumbersButton          matlab.ui.control.Button
        ZenithTroposphericDelayButton   matlab.ui.control.Button
        ISBacctoGPSButton               matlab.ui.control.Button
        GPSClockOffsetButton            matlab.ui.control.Button
        NorthEastUpErrorsButton         matlab.ui.control.Button
    end


    properties (Access = public)
        text1; count1=1; rnxdata=[]; rawRinexdata=[]; antenType; rnxtime; rnx_GPS; rnx_GLO;
        rnx_GAL; rnx_BDS; rnx_QZSS; recPosition1; antennaPosition;
        sp3data=[]; GPSsp3;  GLONASSsp3;  GALILEOsp3;  BEIDOUsp3; QZSSsp3; sp3time;
        GPSclock;  GLONASSclock; GALILEOclock; BEIDOUclock; QZSSclock; clocktime;
        satOffset=[]; recOffset; clockdata=[]; markerName; antennaFilename;
        run_CScontrol=0; combination;
        GPS_output;GLO_output;GAL_output;BDS2_output;BDS3_output;QZSS_output;
        output; ground_truth_coor;
        orbFileCont=0;
        clkFileCont=0;

        Lat_ground_trut;Lon_ground_trut; hellp1; north_PPP;east_PPP;up_PPP;
        DOP_values;

        ref_ZTD=[]; int_ZTD;   cont_Value=1;
    end


    methods (Access = private)

        function rinex3_signals(app)
            [app.rnx_GPS]=signals_GPS_v3(app.rnxdata);
            [app.rnx_GLO]=signals_GLO_v3(app.rnxdata);
            [app.rnx_GAL]=signals_GAL_v3(app.rnxdata);
            [app.rnx_BDS]=signals_BDS_v3(app.rnxdata);
            [app.rnx_QZSS]=signals_QZSS_v3(app.rnxdata);

            if app.rnx_GPS.GPS_system==1

                app.GPSLabel.BackgroundColor=[0.48,1.00,0.00];

                signal_G=([app.rnx_GPS.GPS_C1 '  ' app.rnx_GPS.GPS_C2 '  ' app.rnx_GPS.GPS_L1 '  ' app.rnx_GPS.GPS_L2]);
                app.EF_G.Value=signal_G;
            end


            if app.rnx_GLO.GLO_system==1

                app.GLONASSLabel.BackgroundColor=[0.48,1.00,0.00];

                signal_R=([app.rnx_GLO.GLO_C1 '  ' app.rnx_GLO.GLO_C2 '  ' app.rnx_GLO.GLO_L1 '  ' app.rnx_GLO.GLO_L2]);
                app.EF_R.Value=signal_R;
            end

            if app.rnx_GAL.GAL_system==1

                app.GalileoLabel.BackgroundColor=[0.48,1.00,0.00];

                signal_E=([app.rnx_GAL.GAL_C1 '  ' app.rnx_GAL.GAL_C2 '  ' app.rnx_GAL.GAL_L1 '  ' app.rnx_GAL.GAL_L2]);
                app.EF_E.Value=signal_E;
            end

            if app.rnx_BDS.BDS_system==1

                app.BDS2Label.BackgroundColor=[0.48,1.00,0.00];
                app.BDS3Label.BackgroundColor=[0.48,1.00,0.00];

                signal_C=([app.rnx_BDS.BDS_C1 '  ' app.rnx_BDS.BDS_C2 '  ' app.rnx_BDS.BDS_L1 '  ' app.rnx_BDS.BDS_L2]);
                app.EF_C2.Value=signal_C;
                app.EF_C3.Value=signal_C;
            end

            if app.rnx_QZSS.QZSS_system==1

                app.QZSSLabel.BackgroundColor=[0.48,1.00,0.00];

                signal_J=([app.rnx_QZSS.QZSS_C1 '  ' app.rnx_QZSS.QZSS_C2 '  ' app.rnx_QZSS.QZSS_L1 '  ' app.rnx_QZSS.QZSS_L2]);
                app.EF_J.Value=signal_J;
            end


        end

        function rinex2_signals(app)
            [app.rnx_GPS]=signals_GPS_v2(app.rnxdata);
            [app.rnx_GLO]=signals_GLO_v2(app.rnxdata);
            [app.rnx_GAL]=signals_GAL_v2(app.rnxdata);
            [app.rnx_BDS]=signals_BDS_v2;
            [app.rnx_QZSS]=signals_QZSS_v2;

            if app.rnx_GPS.GPS_system==1

                app.GPSLabel.BackgroundColor=[0.48,1.00,0.00];


                signal_G=([app.rnx_GPS.GPS_C1 '  ' app.rnx_GPS.GPS_C2 '  ' app.rnx_GPS.GPS_L1 '  ' app.rnx_GPS.GPS_L2]);
                app.EF_G.Value=signal_G;
            end


            if app.rnx_GLO.GLO_system==1
                app.GLONASSLabel.BackgroundColor=[0.48,1.00,0.00];

                signal_R=([app.rnx_GLO.GLO_C1 '  ' app.rnx_GLO.GLO_C2 '  ' app.rnx_GLO.GLO_L1 '  ' app.rnx_GLO.GLO_L2]);
                app.EF_R.Value=signal_R;
            end

            if app.rnx_GAL.GAL_system==1
                app.GalileoLabel.BackgroundColor=[0.48,1.00,0.00];

                signal_E=([app.rnx_GAL.GAL_C1 '  ' app.rnx_GAL.GAL_C2 '  ' app.rnx_GAL.GAL_L1 '  ' app.rnx_GAL.GAL_L2]);
                app.EF_E.Value=signal_E;
            end

            if app.rnx_BDS.BDS_system==1
                app.BDS2Label.BackgroundColor=[0.48,1.00,0.00];
                app.BDS3Label.BackgroundColor=[0.48,1.00,0.00];

                signal_C=([app.rnx_BDS.BDS_C1 '  ' app.rnx_BDS.BDS_C2 '  ' app.rnx_BDS.BDS_L1 '  ' app.rnx_BDS.BDS_L2]);
                app.EF_C2.Value=signal_C;
                app.EF_C3.Value=signal_C;
            end

            if app.rnx_QZSS.QZSS_system==1
                app.QZSSLabel.BackgroundColor=[0.48,1.00,0.00];

                signal_J=([app.rnx_QZSS.QZSS_C1 '  ' app.rnx_QZSS.QZSS_C2 '  ' app.rnx_QZSS.QZSS_L1 '  ' app.rnx_QZSS.QZSS_L2]);
                app.EF_J.Value=signal_J;
            end


        end

        function [filter_par,stoc] = filtering_parameters(app)
            %%%%%filter parameters
            filter_par.int_coord=app.CoordinatesEditField.Value;
            filter_par.int_GPS_rec= app.GPSReceiverClockEditField.Value;
            filter_par.int_zenit_wet=app.ZenithWetDelayEditField.Value;
            filter_par.int_IBSs=app.InterSystemBiasesEditField.Value;
            filter_par.int_Amb=app.FloatAmbiguitiesEditField.Value;
            %%%System Noise
            filter_par.noise_coord=app.CoordinatesNoise.Value;
            filter_par.noise_GPS_rec=app.GPSReceiverClockNoise.Value;
            filter_par.noise_zenit_wet=app.ZenithWetDelayNoise.Value;
            filter_par.noise_IBSs=app.InterSystemBiasesNoise.Value;
            filter_par.noise_Amb=app.FloatAmbiguitiesNoise.Value;
            %%% Std of measurement
            stoc.code_std=app.CodeEditField.Value;
            stoc.phase_std=app.PhaseEditField.Value;
            %%% GNSS weighting
            stoc.code_GPS_weight=app.Code_GPS_weight.Value;
            stoc.code_GLO_weight=app.Code_GLO_weight.Value;
            stoc.code_GAL_weight=app.Code_GAL_weight.Value;
            stoc.code_BDSmeo_weight=app.Code_Cmeo_weight.Value;
            stoc.code_BDSgeo_weight=app.Code_Cgeo_weight.Value;
            stoc.code_BDSigso_weight=app.Code_Cigso_weight.Value;
            stoc.code_QZSS_weight=app.Code_QZSS_weight.Value;

            stoc.phase_GPS_weight=app.Phase_GPS_weight.Value;
            stoc.phase_GLO_weight=app.Phase_GLO_weight.Value;
            stoc.phase_GAL_weight=app.Phase_GAL_weight.Value;
            stoc.phase_BDSmeo_weight=app.Phase_Cmeo_weight.Value;
            stoc.phase_BDSgeo_weight=app.Phase_Cgeo_weight.Value;
            stoc.phase_BDSigso_weight=app.Phase_Cigso_weight.Value;
            stoc.phase_QZSS_weight=app.Phase_QZSS_weight.Value;

            if app.ElevationAngleDependentButton.Value==1
                stoc.model=1;
            elseif app.CN0DependentButton.Value==1
                stoc.model=2;
            elseif app.ElevationAngleCN0DependentButton.Value==1
                stoc.model=3;
            end

        end

        function [mod_Opt] = functionel_modeling(app)
            mod_Opt.MCoffset=app.SatellitePhaseCenterOffsetCheckBox.Value;
            mod_Opt.solid_tides=app.SolidTideCheckBox.Value;
            mod_Opt.anten1=app.ReceiverPhaseCenterOffsetCheckBox.Value;
            mod_Opt.eclipse=app.SatelliteEclipseConditionCheckBox.Value;
            mod_Opt.pco=app.ReceiverPhaseCenterVariationCheckBox.Value;
            mod_Opt.anten_height1=app.ReceiverAntennaReferencePointCheckBox.Value;
            mod_Opt.windEf=app.SatelliteWindupEffectCheckBox.Value;


        end

        function combination_selection(app)
            app.PPPCombinationsPanel.Visible="on";
            if app.rnx_GPS.GPS_system==1
                app.GButton.Enable="on";
            end

            if app.rnx_GPS.GPS_system==1 && app.rnx_GLO.GLO_system==1
                app.GRButton.Enable="on";
            end


            if app.rnx_GPS.GPS_system==1 && app.rnx_GAL.GAL_system==1
                app.GEButton.Enable="on";
            end


            if app.rnx_GPS.GPS_system==1 && app.rnx_GLO.GLO_system==1 && app.rnx_GAL.GAL_system==1
                app.GREButton.Enable="on";
            end


            if app.rnx_GPS.GPS_system==1 && app.rnx_GLO.GLO_system==1 && app.rnx_GAL.GAL_system==1 && app.rnx_BDS.BDS_system==1
                app.GREC2Button.Enable="on";
                app.GREC3Button.Enable="on";
                app.GREC2C3Button.Enable="on";
            end

            if app.rnx_GPS.GPS_system==1 && app.rnx_GLO.GLO_system==1 && app.rnx_GAL.GAL_system==1 && app.rnx_BDS.BDS_system==1 && app.rnx_QZSS.QZSS_system==1

                app.GREC2C3JButton.Enable="on";
            end


        end

        function restart_rinexRead(app)

            app.GPSLabel.BackgroundColor='none';
            app.GLONASSLabel.BackgroundColor='none';
            app.GalileoLabel.BackgroundColor='none';
            app.BDS2Label.BackgroundColor='none';
            app.BDS3Label.BackgroundColor='none';
            app.QZSSLabel.BackgroundColor='none';

            app.EF_G.Value="";
            app.EF_R.Value="";
            app.EF_E.Value="";
            app.EF_C2.Value="";
            app.EF_C3.Value="";
            app.EF_J.Value="";

            app.GButton.Enable="off";
            app.GRButton.Enable="off";
            app.GEButton.Enable="off";
            app.GREButton.Enable="off";
            app.GREC2Button.Enable="off";
            app.GREC3Button.Enable="off";
            app.GREC2C3Button.Enable="off";
            app.GREC2C3JButton.Enable="off";

            app.GButton.Value=1;


        end

        function run_Control(app)
            if isempty(app.rnxdata)
                msgbox('The Rinex file not found!! Please provide a Rinex file.');
                app.cont_Value=0;

            elseif isempty(app.sp3data)
                msgbox('The SP3 file not found!! Please provide a SP3 file.');
                app.cont_Value=0;

            elseif isempty(app.clockdata)
                msgbox('The Clock file not found!! Please provide a Clock file.');
                app.cont_Value=0;

            elseif isempty(app.satOffset)
                msgbox('The Antenna file not found!! Please provide a Antenna file.');
                app.cont_Value=0;
            else
                app.cont_Value=1;


            end

        end

        function Buttons_Close(app)

            app.NorthEastUpErrorsButton.Enable="off";
            app.ZenithTroposphericDelayButton.Enable="off";
            app.SatelliteVisibilityChartButton.Enable="off";
            app.GDOPandPDOPValuesButton.Enable="off";
            app.SkyPlotAnalysisButton.Enable="off";
            app.HorizontalErrorsButton.Enable="off";
            app.GPSClockOffsetButton.Enable="off";
            app.ISBacctoGPSButton.Enable="off";
            app.CN0AnalysisButton.Enable="off";
            app.SatelliteNumbersButton.Enable="off";
            app.CalculateButton.Enable="off";
            app.PLOTButton.Enable="off";


        end

        function Buttons_Open(app)
            app.NorthEastUpErrorsButton.Enable="on";
            app.ZenithTroposphericDelayButton.Enable="on";
            app.SatelliteVisibilityChartButton.Enable="on";
            app.GDOPandPDOPValuesButton.Enable="on";
            app.SkyPlotAnalysisButton.Enable="on";
            app.HorizontalErrorsButton.Enable="on";
            app.GPSClockOffsetButton.Enable="on";
            app.ISBacctoGPSButton.Enable="on";
            app.CN0AnalysisButton.Enable="on";
            app.SatelliteNumbersButton.Enable="on";
            app.CalculateButton.Enable="on";
            app.PLOTButton.Enable="on";

        end

        function final_informations(app,X)
            if app.GButton.Value==1
                app.Combination.Value=string(app.markerName)+"  Station  -----  "+"G Combination";

                app.text1{app.count1,1}=char(sprintf("The PPP processing has been successfully completed." +"\n"));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Station  .......  "+"G Combination"  +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Coordinates: X:%13.4f m, Y:%13.4f m, Z:%13.4f m"  +"\n",X(1:3,1)));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("The solution results have been saved in Results_Files/%s klasor. "+"\n",(app.markerName)));
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("--------------------------------------------------------------------------------"+"\n"));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


            elseif app.GRButton.Value==1
                app.Combination.Value=string(app.markerName)+"  Station  -----  "+"GR Combination";

                app.text1{app.count1,1}=char(sprintf("The PPP processing has been successfully completed." +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Station  .......  "+"GR Combination"  +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Coordinates: X:%13.4f m, Y:%13.4f m, Z:%13.4f m"  +"\n",X(1:3,1)));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("The solution results have been saved in Results_Files/%s klasor. "+"\n",(app.markerName)));
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("--------------------------------------------------------------------------------"+"\n"));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


            elseif app.GEButton.Value==1
                app.Combination.Value=string(app.markerName)+"  Station  -----  "+"GE Combination";

                app.text1{app.count1,1}=char(sprintf("The PPP processing has been successfully completed." +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Station  .......  "+"GE Combination"  +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Coordinates: X:%13.4f m, Y:%13.4f m, Z:%13.4f m"  +"\n",X(1:3,1)));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("The solution results have been saved in Results_Files/%s klasor. "+"\n",(app.markerName)));
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("--------------------------------------------------------------------------------"+"\n"));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

            elseif app.GREButton.Value==1
                app.Combination.Value=string(app.markerName)+"  Station  -----  "+"GRE Combination";
                app.text1{app.count1,1}=char(sprintf("The PPP processing has been successfully completed." +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Station  .......  "+"GRE Combination"  +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Coordinates: X:%13.4f m, Y:%13.4f m, Z:%13.4f m"  +"\n",X(1:3,1)));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("The solution results have been saved in Results_Files/%s klasor. "+"\n",(app.markerName)));
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("--------------------------------------------------------------------------------"+"\n"));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

            elseif app.GREC2Button.Value==1
                app.Combination.Value=string(app.markerName)+"  Station  -----  "+"GREC2 Combination";
                app.text1{app.count1,1}=char(sprintf("The PPP processing has been successfully completed." +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Station  .......  "+"GREC2 Combination"  +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Coordinates: X:%13.4f m, Y:%13.4f m, Z:%13.4f m"  +"\n",X(1:3,1)));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("The solution results have been saved in Results_Files/%s klasor. "+"\n",(app.markerName)));
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("--------------------------------------------------------------------------------"+"\n"));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

            elseif app.GREC3Button.Value==1
                app.Combination.Value=string(app.markerName)+"  Station  -----  "+"GREC3 Combination";
                app.text1{app.count1,1}=char(sprintf("The PPP processing has been successfully completed." +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Station  .......  "+"GREC3 Combination"  +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Coordinates: X:%13.4f m, Y:%13.4f m, Z:%13.4f m"  +"\n",X(1:3,1)));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("The solution results have been saved in Results_Files/%s klasor. "+"\n",(app.markerName)));
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("--------------------------------------------------------------------------------"+"\n"));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

            elseif app.GREC2C3Button.Value==1
                app.Combination.Value=string(app.markerName)+"  Station  -----  "+"GREC2C3 Combination";

                app.text1{app.count1,1}=char(sprintf("The PPP processing has been successfully completed." +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Station  .......  "+"GREC2C3 Combination"  +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Coordinates: X:%13.4f m, Y:%13.4f m, Z:%13.4f m"  +"\n",X(1:3,1)));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("The solution results have been saved in Results_Files/%s klasor. "+"\n",(app.markerName)));
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("--------------------------------------------------------------------------------"+"\n"));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

            elseif app.GREC2C3JButton.Value==1
                app.Combination.Value=string(app.markerName)+"  Station  -----  "+"GREC2C3J Combination";
                app.text1{app.count1,1}=char(sprintf("The PPP processing has been successfully completed." +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Station  .......  "+"GREC2C3J Combination"  +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

                app.text1{app.count1,1}=char(sprintf((app.markerName)+"  Coordinates: X:%13.4f m, Y:%13.4f m, Z:%13.4f m"  +"\n",X(1:3,1)));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("The solution results have been saved in Results_Files/%s klasor. "+"\n",(app.markerName)));
                app.count1=app.count1+1;


                app.text1{app.count1,1}=char(sprintf("--------------------------------------------------------------------------------"+"\n"));

                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.UIFigure.Name ='APAS-TR';
            app.UIFigure.Resize="off";


            app.TabGroup.Visible = 'off'  ;

            app.TabGroup.Visible = 'on'  ;
            app.TabGroup.SelectedTab = app.RinexTab;
            app.SatellitePhaseCenterOffsetCheckBox.Value=1;
            app.SatelliteWindupEffectCheckBox.Value=1;
            app.SatelliteEclipseConditionCheckBox.Value=1;
            app.ReceiverPhaseCenterOffsetCheckBox.Value=1;
            app.ReceiverAntennaReferencePointCheckBox.Value=1;
            app.ReceiverPhaseCenterVariationCheckBox.Value=1;
            app.ReceiverClockJumpDetectionCheckBox.Value=1;
            app.SolidTideCheckBox.Value=1;
            app.SatelliteElevationCutOffEditField.Value=8;
            app.SatelliteCN0CutOffEditField.Value=20;
            app.NMF.Value=1;


            app.SinexFileButton.Visible='off';
            app.SinexPathEditField.Visible="off";
            app.SinexPathEditFieldLabel.Visible="off";

            app.ConvergenceCriteriaEditField.Value=0.10;
            app.ConvergenceCriteriaEditField.ValueDisplayFormat='%5.3f';


            %%%filtering section
            %%%Inıtıal Std
            app.CoordinatesEditField.Value=1000;
            app.GPSReceiverClockEditField.Value=1000;
            app.ZenithWetDelayEditField.Value=0.5;
            app.InterSystemBiasesEditField.Value=1000;
            app.FloatAmbiguitiesEditField.Value=20;
            %%%System Noise

            app.CoordinatesNoise.Value=0;
            app.GPSReceiverClockNoise.Value=1000;
            app.ZenithWetDelayNoise.Value=0.01;
            app.InterSystemBiasesNoise.Value=1000;
            app.FloatAmbiguitiesNoise.Value=0;

            %%% Std of measurement

            app.CodeEditField.Value=0.30;
            app.PhaseEditField.Value=0.002;

            %%% GNSS weighting
            app.Code_GPS_weight.Value=1;
            app.Code_GLO_weight.Value=1;
            app.Code_GAL_weight.Value=1;
            app.Code_Cmeo_weight.Value=1;
            app.Code_Cigso_weight.Value=1;
            app.Code_Cgeo_weight.Value=1;
            app.Code_QZSS_weight.Value=1;

            app.Phase_GPS_weight.Value=1;
            app.Phase_GLO_weight.Value=1;
            app.Phase_GAL_weight.Value=1;
            app.Phase_Cmeo_weight.Value=1;
            app.Phase_Cigso_weight.Value=1;
            app.Phase_Cgeo_weight.Value=1;
            app.Phase_QZSS_weight.Value=1;


            app.PPPCombinationsPanel.Visible="on";
            app.GButton.Enable="off";
            app.GRButton.Enable="off";
            app.GEButton.Enable="off";
            app.GREButton.Enable="off";
            app.GREC2Button.Enable="off";
            app.GREC3Button.Enable="off";
            app.GREC2C3Button.Enable="off";
            app.GREC2C3JButton.Enable="off";


            app.GButton.Enable="off";
            app.GRButton.Enable="off";
            app.GEButton.Enable="off";
            app.GREButton.Enable="off";
            app.GREC2Button.Enable="off";
            app.GREC3Button.Enable="off";
            app.GREC2C3Button.Enable="off";
            app.GREC2C3JButton.Enable="off";


            app.SystemListBox.Visible="off";
            app.SystemListBoxLabel.Visible="off";
            app.PRNEditField.Value=1;
            app.PRNEditField.Visible="off";
            app.PRNEditFieldLabel.Visible="off";
            app.ObservationListBox.Visible="off";
            app.ObservationListBoxLabel.Visible="off";
            app.AcctoListBox.Visible="off";
            app.AcctoListBoxLabel.Visible="off";


            Buttons_Close(app) %%%before run Button

            %%%%end of the startupFnc


        end

        % Menu selected function: RinexDataMenu
        function RinexDataMenuSelected(app, event)
            app.TabGroup.Visible = 'on'  ;
            app.TabGroup.SelectedTab = app.RinexTab;
            %app.OpeningPage.Value='' ;
        end

        % Button pushed function: RinexFileButton
        function RinexFileButtonPushed(app, event)
            s = what('APAS_TR');
            m_prog=s.path;
            m_path=([m_prog '\Inputs\Rinex_Data' ]);

            [rinexFilename,rinexpath,index] = uigetfile({'*' }, ...
                'Select a file',m_path);
            drawnow;
            figure(app.UIFigure)

            if index > 0


                version_Rinex=rinex_Version(rinexFilename);
                if isempty(version_Rinex)
                    errordlg('The Rinex file was not found. Please choose a file in the Rinex format');
                end

                drawnow;
                figure(app.UIFigure)

                if version_Rinex == 3
                    app.RinexFileEditField.Value = 'Reading the Rinex file....Please Wait...';
                    app.RinexFileEditField.FontColor='#49006a';

                    app.text1{app.count1,1}= char(sprintf('Reading the Rinex file >>>>>>:' +string(rinexFilename)+'\n'));
                    app.StatusTextArea.Value=(app.text1);
                    app.count1=app.count1+1;
                    restart_rinexRead(app);
                    drawnow;

                    [app.rnxdata, app.rnxtime, app.recPosition1, app.antennaPosition, app.antenType, app.markerName]=read_Rinex_Ver3(rinexFilename);


                    app.run_CScontrol=0; %% for new rinex file cycle slip control

                    app.text1{app.count1,1}=char(sprintf("The Rinex file was read.  >>>>>" +"\n"));
                    app.StatusTextArea.Value=app.text1;
                    app.count1=app.count1+1;
                    app.RinexFileEditField.Value=string(rinexFilename);
                    app.RinexFileEditField.FontSize=12;
                    app.RinexFileEditField.FontColor='k';
                    rinex3_signals(app)
                    app.rawRinexdata=app.rnxdata; %%%for control orbit and clock product

                elseif version_Rinex == 2
                    app.RinexFileEditField.Value = 'Reading the Rinex file....Please Wait...';
                    app.RinexFileEditField.FontColor='#49006a';

                    app.text1{app.count1,1}= char(sprintf('Reading the Rinex file >>>>>>:' +string(rinexFilename)+'\n'));
                    app.StatusTextArea.Value=(app.text1);
                    app.count1=app.count1+1;
                    restart_rinexRead(app);
                    drawnow

                    [app.rnxdata, app.rnxtime, app.recPosition1, app.antennaPosition, app.antenType, app.markerName]=read_Rinex_Ver2(rinexFilename);

                    app.run_CScontrol=0; %% for new rinex file cycle slip control

                    app.text1{app.count1,1}=char(sprintf("The Rinex file was read.  >>>>>" +"\n"));
                    app.StatusTextArea.Value=app.text1;
                    app.count1=app.count1+1;
                    app.RinexFileEditField.Value=string(rinexFilename);
                    app.RinexFileEditField.FontColor='k';
                    app.RinexFileEditField.FontSize=12;

                    rinex2_signals(app);
                    app.rawRinexdata=app.rnxdata; %%%for control orbit and clock product

                end

                combination_selection(app);

            end

        end

        % Menu selected function: OrbitandClockProductsMenu
        function OrbitandClockProductsMenuSelected(app, event)
            app.TabGroup.Visible = 'on'  ;
            app.TabGroup.SelectedTab = app.OrbClkTab;
            %app.OpeningPage.Value='' ;
        end

        % Button pushed function: OrbitFileButton
        function OrbitFileButtonPushed(app, event)

            s = what('APAS_TR');
            m_prog=s.path;
            m_path=([m_prog '\Inputs\Orbit_Products' ]);

            [sp3Filename,sp3path,index] = uigetfile({'*' }, ...
                'Select a file',m_path);

            drawnow;
            figure(app.UIFigure)

            if index > 0

                app.OrbitFileEditField.Value = 'Reading the SP3-Orbit file....Please Wait...';
                app.OrbitFileEditField.FontColor='#49006a';
                app.text1{app.count1,1}= char(sprintf('Reading the SP3-Orbit file >>>>>>:' +string(sp3Filename)+'\n'));
                app.StatusTextArea.Value=(app.text1);
                app.count1=app.count1+1;
                drawnow;
                [app.sp3data,app.sp3time]=read_Sp3(sp3Filename);
                app.GPSsp3=app.sp3data.GPS;
                app.GLONASSsp3=app.sp3data.GLONASS;
                app.GALILEOsp3=app.sp3data.GALILEO;
                app.BEIDOUsp3=app.sp3data.BEIDOU;
                app.QZSSsp3=app.sp3data.QZSS;


                app.text1{app.count1,1}=char(sprintf("The SP3-Orbit file was read  >>>>>" +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;
                app.OrbitFileEditField.Value=string(sp3Filename);
                app.OrbitFileEditField.FontColor='k';
                app.OrbitFileEditField.FontSize=12;
                app.orbFileCont=0;
            end


        end

        % Button pushed function: ClockFileButton
        function ClockFileButtonPushed(app, event)

            s = what('APAS_TR');
            m_prog=s.path;
            m_path=([m_prog '\Inputs\Clock_Products' ]);

            [clkFilename,clockpath,index] = uigetfile({'*' }, ...
                'Select a file',m_path);

            drawnow;
            figure(app.UIFigure)

            if index > 0
                [clk_version ] = read_Clock_version(clkFilename);


                app.ClockFileEditField.Value = 'Reading the Clock file....Please Wait...';
                app.ClockFileEditField.FontColor='#49006a';

                app.text1{app.count1,1}= char(sprintf('Reading the Clock file >>>>>>:' +string(clkFilename)+'\n'));
                app.StatusTextArea.Value=(app.text1);
                app.count1=app.count1+1;

                drawnow;

                if clk_version > 3.02
                    [app.clockdata,app.clocktime ] = read_ClockFile_multi(clkFilename);

                elseif clk_version <= 3.02
                    [app.clockdata,app.clocktime ] = read_ClockFile(clkFilename);
                end

                app.GPSclock=app.clockdata.GPS_clkbias;
                app.GLONASSclock=app.clockdata.GLONASS_clkbias;
                app.GALILEOclock=app.clockdata.GALILEO_clkbias;
                app.BEIDOUclock=app.clockdata.BEIDOU_clkbias;
                app.QZSSclock=app.clockdata.QZSS_clkbias;

                app.text1{app.count1,1}=char(sprintf("The Clock file was read  >>>>>" +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;
                app.ClockFileEditField.Value=string(clkFilename);
                app.ClockFileEditField.FontColor='k';
                app.ClockFileEditField.FontSize=12;
                app.clkFileCont=0;
            end


        end

        % Menu selected function: AntennaandDCBFilesMenu
        function AntennaandDCBFilesMenuSelected(app, event)
            app.TabGroup.Visible = 'on'  ;
            app.TabGroup.SelectedTab = app.AntexTab;
            %app.OpeningPage.Value='' ;
        end

        % Button pushed function: AntexFileButton
        function AntexFileButtonPushed(app, event)
            s = what('APAS_TR');
            m_prog=s.path;
            m_path=([m_prog '\Inputs\Antenna_Files' ]);

            [app.antennaFilename,antennapath,index] = uigetfile({'*' }, ...
                'Select a file',m_path);
            drawnow;
            figure(app.UIFigure)

            if index > 0

                app.AntennaFileEditField.Value = 'Reading the Antenna file....Please Wait...';
                app.AntennaFileEditField.FontColor ='#49006a';
                app.text1{app.count1,1}= char(sprintf('Reading the Antenna file >>>>>>:' +string(app.antennaFilename)+'\n'));
                app.StatusTextArea.Value=(app.text1);
                app.count1=app.count1+1;
                drawnow;
                MJD=app.rnxtime(1,3);

                [app.satOffset, app.recOffset]=read_AntexFile(MJD,app.antennaFilename,app.antenType );


                app.text1{app.count1,1}=char(sprintf("The Antenna file was read  >>>>>" +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;
                app.AntennaFileEditField.Value=string(app.antennaFilename);
                app.AntennaFileEditField.FontColor ='k';
                app.AntennaFileEditField.FontSize=12;
            end
        end

        % Button pushed function: DCBFileButton
        function DCBFileButtonPushed(app, event)
            s = what('APAS_TR');
            m_prog=s.path;
            m_path=([m_prog '\Inputs\DCB_Files' ]);

            [DCBFilename,~,index] = uigetfile({'*' }, ...
                'Select a file',m_path);
            drawnow;
            figure(app.UIFigure)
            if index > 0


                app.DCBFileEditField.Value = 'Reading the DCB file....Please Wait...';
                app.DCBFileEditField.FontColor ='#49006a';
                app.text1{app.count1,1}= char(sprintf('Reading the DCB file >>>>>>:' +string(DCBFilename)+'\n'));
                app.StatusTextArea.Value=(app.text1);
                app.count1=app.count1+1;
                drawnow;


                [app.rnxdata]=applied_DCB(app.rnxdata,app.rnx_GPS,app.rnx_GLO, DCBFilename);
                app.rawRinexdata=app.rnxdata; %%%%%%!!!

                app.text1{app.count1,1}=char(sprintf("The DCB file was read  >>>>>" +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;
                app.DCBFileEditField.Value=string(DCBFilename);
                app.DCBFileEditField.FontColor ='k';
                app.DCBFileEditField.FontSize=12;
            end


        end

        % Menu selected function: SatelliteandReceiverDependentMenu
        function SatelliteandReceiverDependentMenuSelected(app, event)
            app.TabGroup.Visible = 'on'  ;
            app.TabGroup.SelectedTab = app.M1Tab;
            %app.OpeningPage.Value='' ;

        end

        % Menu selected function: AtmosphereMenu
        function AtmosphereMenuSelected(app, event)
            app.TabGroup.Visible = 'on'  ;
            app.TabGroup.SelectedTab = app.M2Tab;
            %app.OpeningPage.Value='' ;
        end

        % Menu selected function: AdjustmentSettingsMenu
        function AdjustmentSettingsMenuSelected(app, event)
            app.TabGroup.Visible = 'on'  ;
            app.TabGroup.SelectedTab = app.FilterTab;
            %app.OpeningPage.Value='' ;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

            SNR_cutoff=app.SatelliteCN0CutOffEditField.Value;
            cut_Off_Elev=app.SatelliteElevationCutOffEditField.Value;

            if app.GButton.Value==1; app.combination=1;
            elseif app.GRButton.Value==1; app.combination=2;
            elseif app.GEButton.Value==1; app.combination=3;
            elseif app.GREButton.Value==1; app.combination=4;
            elseif app.GREC2Button.Value==1; app.combination=5;
            elseif app.GREC3Button.Value==1; app.combination=6;
            elseif app.GREC2C3Button.Value==1; app.combination=7;
            elseif app.GREC2C3JButton.Value==1; app.combination=8;
            end


            run_Control(app)%% data and product avaliable control


            if app.cont_Value==0

            else

                [combiProCont]= Combi_ProductContol(app.sp3data,app.combination); %% combination and GNSS products control

                if combiProCont==1


                else


                    if app.run_CScontrol==0 || app.orbFileCont==0 || app.clkFileCont==0
                        

                        app.rnxdata=app.rawRinexdata; %% rinex data,orbit and clok control
                        app.XEditField.Value=0;
                        app.XEditField.ValueDisplayFormat='%12.4f';
                        app.YEditField.Value=0;
                        app.YEditField.ValueDisplayFormat='%12.4f';
                        app.ZEditField.Value=0;
                        app.ZEditField.ValueDisplayFormat='%12.4f';
                        app.SinexPathEditField.Value='';
                        app.ZTDPathEditField.Value='';


                        app.output=[];
                        res=[];
                        app.DOP_values=[];

                        MJD=app.rnxtime(1,3);

                        [app.satOffset, app.recOffset]=read_AntexFile(MJD,app.antennaFilename,app.antenType );


                        %%%% clock jump detection and repair
                        [app.rnxdata]=clock_Jump_Control(app.rnxdata,app.rnxtime,app.rnx_GPS,app.rnx_GLO,app.rnx_GAL,app.rnx_BDS,app.rnx_QZSS);


                        %%%% cycle slip detection and repair
                        if app.UseSmoothCodeMeasurementsCheckBox.Value==1
                            Smooth=1;
                        else
                            Smooth=0;
                        end

                        if app.ROTIbasedGFCombinationFBMWAMWCombinationButton.Value==1

                            [app.rnxdata]=cycle_Slip_Control(app.clockdata, app.clocktime, app.sp3data, app.sp3time,app.rnxdata,app.rnxtime,app.recPosition1,app.rnx_GPS,app.rnx_GLO,app.rnx_GAL,app.rnx_BDS,app.rnx_QZSS,cut_Off_Elev,Smooth);
                        elseif app.TurboEditAlgorithmButton.Value==1
                            % msgbox('Turbo seçildii...');


                            [app.rnxdata]=cycle_Slip_Control_TuEdit(app.clockdata, app.clocktime, app.sp3data, app.sp3time,app.rnxdata,app.rnxtime,app.recPosition1,app.rnx_GPS,app.rnx_GLO,app.rnx_GAL,app.rnx_BDS,app.rnx_QZSS,cut_Off_Elev,Smooth);
                        end
                        app.run_CScontrol=1;
                        app.orbFileCont=1;
                        app.clkFileCont=1;


                    else
                        app.output=[];
                        res=[];
                        app.DOP_values=[];

                    end


                    [latitude,longitude,hellp]=xyz2eby(app.recPosition1);
                    grid_control=zeros(8,1);
                    if app.VMF3.Value==1
                        Trop_Model=1;
                        msgbox('Downloading the VMF3 files...');
                        VMF3_parameter_Download(app.rnxtime(1,4), app.rnxtime(1,5),app.rnxtime(1,6));
                        VMF3_parameter_Download(app.rnxtime(1,4), app.rnxtime(1,5),app.rnxtime(1,6)+1);

                        msgbox('The VMF3 files were downloaded...');

                    elseif app.VMF1.Value==1
                        Trop_Model=2;
                        VMF1_parameter_Download(app.rnxtime(1,4), app.rnxtime(1,5),app.rnxtime(1,6));
                        msgbox('Downloading the VMF1 files...');
                        VMF1_parameter_Download(app.rnxtime(1,4), app.rnxtime(1,5),app.rnxtime(1,6));
                        VMF1_parameter_Download(app.rnxtime(1,4), app.rnxtime(1,5),app.rnxtime(1,6)+1);

                        msgbox('The VMF1 files downloaded...');

                    elseif app.GPT3.Value==1
                        Trop_Model=3;
                       % msgbox('GPT3 was selected...');

                    elseif app.GPT2.Value==1
                        Trop_Model=4;
                        %msgbox('GPT2 was selected...');
                    elseif app.NMF.Value==1
                        Trop_Model=5;
                    end

                    [tro_prmtr]=trop_Option(Trop_Model,app.rnxtime(1,3) , latitude*pi/180 , longitude*pi/180 , hellp , 40*pi/180);


                    %%%receiver informations
                    rec_Pre_Info.recPosition=app.recPosition1;
                    rec_Pre_Info.latitude=latitude;
                    rec_Pre_Info.longitude=longitude;
                    rec_Pre_Info.anten_Pos=app.antennaPosition;
                    rec_Pre_Info.hellp=hellp;


                    %%%for initializing
                    CXX=[1000000 0 0 0 ; 0 1000000 0 0 ; 0 0 10000000 0 ;0 0 0 10^10];
                    X=0;
                    prevGPS=0; prevGLO=0; prevGAL=0; prevBDS2=0; prevBDS3=0; prevQZSS=0;

                    xsat.G=0; xsat.R=0; xsat.E=0; xsat.C2=0; xsat.C3=0; xsat.GE=0; xsat.GR=0;  xsat.GRE=0; xsat.GREC2=0; xsat.GREC3=0;
                    xsat.GREC2C3=0; xsat.J=0; xsat.GREC2C3J=0; xsat.GJ=0;

                    [mod_Opt] = functionel_modeling(app);
                    [filter_par,stoc] = filtering_parameters(app);

                    [fh,ph, th]=wait_bar_process(app.markerName);


                    for s=1:length(app.rnxtime)

                        if Trop_Model==1 || Trop_Model==2
                            [tro_prmtr,grid_control]=trop_Grid_Control(app.rnxtime(s,3),latitude,longitude,hellp,grid_control,Trop_Model,tro_prmtr);
                        end

                        [obs_C_GPS,obs_L_GPS, GPS_SNR]=observation_GPS(app.rnxdata,app.rnx_GPS,SNR_cutoff,s);
                        obs_GPS1=[obs_C_GPS;obs_L_GPS];


                        [Sat_Info_GPS]=Sat_Information(rec_Pre_Info,obs_C_GPS,app.rnxtime,app.GPSsp3,app.sp3time,app.GPSclock,app.clocktime,s);
                        Sat_Info_GPS.GPS_SNR=GPS_SNR;

                        [AGPS,CGPS,lGPS,obs_GPS,XyeniGPS,ZHD,ZWD,prevGPS,trop,satElevationGPS,G_SNR,satAzimuth]=Design_GPS(mod_Opt,stoc,cut_Off_Elev,tro_prmtr,app.combination,X,xsat,obs_GPS1,rec_Pre_Info,Sat_Info_GPS,app.satOffset.GPS,app.recOffset,app.rnxtime,prevGPS,s);

                        GPS_matrix.A=AGPS;  GPS_matrix.C=CGPS;  GPS_matrix.l=lGPS; GPS_matrix.obs=obs_GPS;
                        GPS_matrix.Elev=satElevationGPS;  GPS_matrix.SNR=G_SNR; GPS_matrix.azi=satAzimuth;


                        if app.combination ==2 || (app.combination>=4 && app.combination<=8)
                            %%%%%%%%%GLONASS Gözlemleri

                            [obs_C_GLO,obs_L_GLO,GLO_SNR]=observation_GLO(app.rnxdata,app.rnx_GLO,SNR_cutoff,s);
                            obs_GLO1=[obs_C_GLO;obs_L_GLO];

                            if isempty(obs_C_GLO)

                                AGLO=[];CGLO=[];lGLO=[];obs_GLO=[];XyeniGLO=[];satElevationGLO=[];prevGLO=0;R_SNR=[];R_satAzimuth=[];

                            else

                                [Sat_Info_GLO]=Sat_Information(rec_Pre_Info,obs_C_GLO,app.rnxtime,app.GLONASSsp3,app.sp3time,app.GLONASSclock,app.clocktime,s);
                                Sat_Info_GLO.GLO_SNR=GLO_SNR;
                                [AGLO,CGLO,lGLO,obs_GLO,XyeniGLO,satElevationGLO,prevGLO,R_SNR,R_satAzimuth]=Design_GLONASS(mod_Opt,stoc,cut_Off_Elev,tro_prmtr,app.combination,X,xsat,obs_GLO1,rec_Pre_Info,Sat_Info_GLO,app.satOffset.GLONASS,app.recOffset,app.rnxtime,prevGLO,s);

                            end

                            GLO_matrix.A=AGLO;  GLO_matrix.C=CGLO;  GLO_matrix.l=lGLO; GLO_matrix.obs=obs_GLO;
                            GLO_matrix.Elev=satElevationGLO;  GLO_matrix.SNR=R_SNR; GLO_matrix.azi=R_satAzimuth;
                        end


                        if app.combination >2
                            %%%%Galileo Gözlemleri


                            [obs_C_GAL,obs_L_GAL,GAL_SNR]=observation_GAL(app.rnxdata,app.rnx_GAL,SNR_cutoff,s);
                            obs_GAL1=[obs_C_GAL;obs_L_GAL];

                            if isempty(obs_C_GAL)

                                AGAL=[];CGAL=[];lGAL=[];obs_GAL=[];XyeniGAL=[];satElevationGAL=[];prevGAL=0;E_SNR=[];E_azimuth=[];
                            else


                                [Sat_Info_GAL]=Sat_Information(rec_Pre_Info,obs_C_GAL,app.rnxtime,app.GALILEOsp3,app.sp3time,app.GALILEOclock,app.clocktime,s);
                                Sat_Info_GAL.GAL_SNR=GAL_SNR;
                                [AGAL,CGAL,lGAL,obs_GAL,XyeniGAL,satElevationGAL,prevGAL,E_SNR,E_azimuth]=Design_Galileo(mod_Opt,stoc,cut_Off_Elev,tro_prmtr,app.combination,X,xsat,obs_GAL1,rec_Pre_Info,Sat_Info_GAL,app.satOffset.GALILEO,app.recOffset,app.rnxtime,prevGAL,s);


                            end

                            GAL_matrix.A=AGAL;  GAL_matrix.C=CGAL;  GAL_matrix.l=lGAL; GAL_matrix.obs=obs_GAL;
                            GAL_matrix.Elev=satElevationGAL; GAL_matrix.SNR=E_SNR;  GAL_matrix.azi=E_azimuth;
                        end


                        if app.combination==5 || app.combination==7 || app.combination==8
                            %%%%BDS2 Gözlemleri
                            [obs_C_BDS,obs_L_BDS, BDS2_SNR]=observation_BDS2(app.rnxdata,app.rnx_BDS,SNR_cutoff,s);
                            obs_BDS21=[obs_C_BDS;obs_L_BDS];

                            if isempty(obs_C_BDS)

                                ABDS2=[];CBDS2=[];lBDS2=[];obs_BDS2=[];XyeniBDS2=[];satElevationBDS2=[];prevBDS2=0;C2_SNR=[];C2_azimuth=[];

                            else


                                [Sat_Info_BDS2]=Sat_Information(rec_Pre_Info,obs_C_BDS,app.rnxtime,app.BEIDOUsp3,app.sp3time,app.BEIDOUclock,app.clocktime,s);
                                Sat_Info_BDS2.BDS2_SNR=BDS2_SNR;


                                [ABDS2,CBDS2,lBDS2,obs_BDS2,XyeniBDS2,satElevationBDS2,prevBDS2,C2_SNR,C2_azimuth]=Design_BDS2(mod_Opt,stoc,cut_Off_Elev, tro_prmtr,app.combination,X,xsat,obs_BDS21,rec_Pre_Info,Sat_Info_BDS2,app.satOffset.BEIDOU,app.recOffset,app.rnxtime,prevBDS2,s);

                            end

                            BDS2_matrix.A=ABDS2;  BDS2_matrix.C=CBDS2;  BDS2_matrix.l=lBDS2; BDS2_matrix.obs=obs_BDS2;
                            BDS2_matrix.Elev=satElevationBDS2; BDS2_matrix.SNR=C2_SNR;  BDS2_matrix.azi=C2_azimuth;


                        end


                        if app.combination>5
                            %%%BDS3 Gözlemlerii
                            [obs_C_BDS3,obs_L_BDS3,BDS3_SNR]=observation_BDS3(app.rnxdata,app.rnx_BDS,SNR_cutoff,s);

                            obs_BDS31=[obs_C_BDS3;obs_L_BDS3];

                            if isempty(obs_C_BDS3)

                                ABDS3=[];CBDS3=[];lBDS3=[];obs_BDS3=[];XyeniBDS3=[];satElevationBDS3=[];prevBDS3=0;C3_SNR=[];C3_azimuth=[];

                            else


                                [Sat_Info_BDS3]=Sat_Information(rec_Pre_Info,obs_C_BDS3,app.rnxtime,app.BEIDOUsp3,app.sp3time,app.BEIDOUclock,app.clocktime,s);
                                Sat_Info_BDS3.BDS3_SNR=BDS3_SNR;

                                [ABDS3,CBDS3,lBDS3,obs_BDS3,XyeniBDS3,satElevationBDS3,prevBDS3,C3_SNR,C3_azimuth]=Design_BDS3(mod_Opt,stoc,cut_Off_Elev, tro_prmtr,app.combination,X,xsat,obs_BDS31,rec_Pre_Info,Sat_Info_BDS3,app.satOffset.BEIDOU,app.recOffset,app.rnxtime,prevBDS3,s);

                            end

                            BDS3_matrix.A=ABDS3;  BDS3_matrix.C=CBDS3;  BDS3_matrix.l=lBDS3; BDS3_matrix.obs=obs_BDS3;

                            BDS3_matrix.Elev=satElevationBDS3; BDS3_matrix.SNR=C3_SNR;  BDS3_matrix.azi=C3_azimuth;

                        end

                        if app.combination>7
                            %%%QZSS Gözlemleri

                            [obs_C_QZSS,obs_L_QZSS,QZSS_SNR]=observation_QZSS(app.rnxdata,app.rnx_QZSS,SNR_cutoff,s);
                            obs_QZSS1=[obs_C_QZSS;obs_L_QZSS];

                            if isempty(obs_C_QZSS)
                                AQZSS=[];CQZSS=[];lQZSS=[];obs_QZSS=[];XyeniQZSS=[];satElevationQZSS=[];prevQZSS=0;J_SNR=[];J_azimuth=[];


                            else

                                [Sat_Info_QZSS]=Sat_Information(rec_Pre_Info,obs_C_QZSS,app.rnxtime,app.QZSSsp3,app.sp3time,app.QZSSclock,app.clocktime,s);
                                Sat_Info_QZSS.QZSS_SNR=QZSS_SNR;

                                [AQZSS,CQZSS,lQZSS,obs_QZSS,XyeniQZSS,satElevationQZSS,prevQZSS,J_SNR,J_azimuth]=Design_QZSS(mod_Opt,stoc,cut_Off_Elev, tro_prmtr,app.combination,X,xsat,obs_QZSS1,rec_Pre_Info,Sat_Info_QZSS,app.satOffset.QZSS,app.recOffset,app.rnxtime,prevQZSS,s);

                            end

                            QZSS_matrix.A=AQZSS;  QZSS_matrix.C=CQZSS;  QZSS_matrix.l=lQZSS; QZSS_matrix.obs=obs_QZSS;
                            QZSS_matrix.Elev=satElevationQZSS;
                            QZSS_matrix.SNR=J_SNR;  QZSS_matrix.azi=J_azimuth;


                        end


                        switch app.combination
                            case 1

                                [X,CXX,xsat,fin_results]=PPP_G(CXX,xsat,XyeniGPS,GPS_matrix,filter_par,app.rnxtime,s);

                                res{s,1}=fin_results;
                                app.DOP_values(s,:)=fin_results.DOPS;
                                app.output(s,:)=[s,app.rnxtime(s,4),app.rnxtime(s,2),app.rnxtime(s,1), X(1:3,1)', X(4,1), trop+X(5,1), 0, 0, 0, 0, 0];

                            case 2

                                [X,CXX,xsat,fin_results]=PPP_GR(XyeniGPS,XyeniGLO,CXX,xsat,GPS_matrix,GLO_matrix,filter_par,app.rnxtime,s);

                                res{s,1}=fin_results;
                                app.DOP_values(s,:)=fin_results.DOPS;
                                app.output(s,:)=[s,app.rnxtime(s,4),app.rnxtime(s,2),app.rnxtime(s,1), X(1:3,1)', X(4,1), trop+X(5,1), X(6,1), 0, 0, 0, 0];

                            case 3

                                [X,CXX,xsat,fin_results]=PPP_GE(XyeniGPS,XyeniGAL,CXX,xsat,GPS_matrix,GAL_matrix,filter_par,app.rnxtime,s);

                                res{s,1}=fin_results;
                                app.DOP_values(s,:)=fin_results.DOPS;
                                app.output(s,:)=[s,app.rnxtime(s,4),app.rnxtime(s,2),app.rnxtime(s,1), X(1:3,1)', X(4,1), trop+X(5,1), 0, X(6,1), 0, 0, 0];

                            case 4

                                [X,CXX,xsat,fin_results]=PPP_GRE(XyeniGPS,XyeniGLO,XyeniGAL,CXX,xsat,GPS_matrix,GLO_matrix,GAL_matrix,filter_par,app.rnxtime,s);

                                res{s,1}=fin_results;
                                app.DOP_values(s,:)=fin_results.DOPS;
                                app.output(s,:)=[s,app.rnxtime(s,4),app.rnxtime(s,2),app.rnxtime(s,1), X(1:3,1)', X(4,1), trop+X(5,1), X(6,1), X(7,1), 0, 0, 0];

                            case 5

                                [X,CXX,xsat,fin_results]=PPP_GREC2(XyeniGPS,XyeniGLO,XyeniGAL,XyeniBDS2,CXX,xsat,GPS_matrix,GLO_matrix,GAL_matrix,BDS2_matrix,filter_par,app.rnxtime,s);

                                res{s,1}=fin_results;
                                app.DOP_values(s,:)=fin_results.DOPS;
                                app.output(s,:)=[s,app.rnxtime(s,4),app.rnxtime(s,2),app.rnxtime(s,1), X(1:3,1)', X(4,1), trop+X(5,1), X(6,1), X(7,1), X(8,1), 0, 0];

                            case 6

                                [X,CXX,xsat,fin_results]=PPP_GREC3(XyeniGPS,XyeniGLO,XyeniGAL,XyeniBDS3,CXX,xsat,GPS_matrix,GLO_matrix,GAL_matrix,BDS3_matrix,filter_par,app.rnxtime,s);

                                res{s,1}=fin_results;
                                app.DOP_values(s,:)=fin_results.DOPS;
                                app.output(s,:)=[s,app.rnxtime(s,4),app.rnxtime(s,2),app.rnxtime(s,1), X(1:3,1)', X(4,1), trop+X(5,1), X(6,1), X(7,1), 0, X(8,1), 0];

                            case 7

                                [X,CXX,xsat,fin_results]=PPP_GREC2C3(XyeniGPS,XyeniGLO,XyeniGAL,XyeniBDS2,XyeniBDS3,CXX,xsat,GPS_matrix,GLO_matrix,GAL_matrix,BDS2_matrix,BDS3_matrix,filter_par,app.rnxtime,s);

                                res{s,1}=fin_results;
                                app.DOP_values(s,:)=fin_results.DOPS;
                                app.output(s,:)=[s,app.rnxtime(s,4),app.rnxtime(s,2),app.rnxtime(s,1), X(1:3,1)', X(4,1), trop+X(5,1), X(6,1), X(7,1), X(8,1), X(9,1), 0];

                            case 8

                                [X,CXX,xsat,fin_results]=PPP_GREC2C3J(XyeniGPS,XyeniGLO,XyeniGAL,XyeniBDS2,XyeniBDS3,XyeniQZSS,CXX,xsat,GPS_matrix,GLO_matrix,GAL_matrix,BDS2_matrix,BDS3_matrix,QZSS_matrix,filter_par,app.rnxtime,s);

                                res{s,1}=fin_results;
                                app.DOP_values(s,:)=fin_results.DOPS;
                                app.output(s,:)=[s,app.rnxtime(s,4),app.rnxtime(s,2),app.rnxtime(s,1), X(1:3,1)', X(4,1), trop+X(5,1), X(6,1), X(7,1), X(8,1), X(9,1), X(10,1)];

                        end


                        % AGPS =[]; CGPS=[]; lGPS=[]; obs_GPS1=[]; obs_C_GPS=[]; obs_L_GPS=[]; obs_GPS=[];
                        % Sat_Info_GPS=[]; XyeniGPS=[]; satElevationGPS=[];
                        %
                        %
                        %
                        % AGLO =[]; CGLO=[]; lGLO=[]; obs_GLO1=[]; obs_C_GLO=[]; obs_L_GLO=[]; obs_GLO=[];
                        % Sat_Info_GLO=[]; XyeniGLO=[]; satElevationGLO=[];
                        %
                        %
                        % AGAL =[]; CGAL=[]; lGAL=[]; obs_GAL1=[]; obs_C_GAL=[]; obs_L_GAL=[]; obs_GAL=[];
                        % Sat_Info_GAL=[]; XyeniGAL=[]; satElevationGAL=[];
                        %
                        %
                        % ABDS2 =[]; CBDS2=[]; lBDS2=[]; obs_BDS21=[]; obs_C_BDS2=[]; obs_L_BDS2=[]; obs_BDS2=[];
                        % Sat_Info_BDS2=[]; XyeniBDS2=[]; satElevationBDS2=[];
                        %
                        %
                        % ABDS3 =[]; CBDS3=[]; lBDS3=[]; obs_BDS31=[]; obs_C_BDS3=[]; obs_L_BDS3=[]; obs_BDS3=[];
                        % Sat_Info_BDS3=[]; XyeniBDS3=[]; satElevationBDS3=[];
                        %
                        %
                        % AQZSS =[]; CQZSS=[]; lQZSS=[]; obs_QZSS1=[]; obs_C_QZSS=[]; obs_L_QZSS=[]; obs_QZSS=[];
                        % Sat_Info_QZSS=[]; XyeniQZSS=[]; satElevationQZSS=[];
                        %
                        %


                        waitbar(s/length(app.rnxtime),fh);
                        ph.XData = [0 s/length(app.rnxtime) s/length(app.rnxtime) 0];
                        th.String = sprintf('%.0f%%',round(s/length(app.rnxtime)*100));
                        drawnow %update graphic


                    end

                    delete(fh)

                    final_informations(app,X)


                    %%%final results analyzing
                    [app.GPS_output,app.GLO_output,app.GAL_output,app.BDS2_output,...
                        app.BDS3_output,app.QZSS_output]=final_results(app.combination,res);


                    %%%%store PPP results as a txt file
                    store_PPPresults(app.output,app.markerName,app.combination);


                    Buttons_Open(app);
                    app.TabGroup.SelectedTab = app.SolAnlysTab;

                end
            end%%%missing file check

        end

        % Selection changed function: GroundTruthCoordinatesButtonGroup
        function GroundTruthCoordinatesButtonGroupSelectionChanged(app, event)
            selectedButton = app.GroundTruthCoordinatesButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'from Sinex File'
                    app.SinexFileButton.Visible='on';
                    app.SinexPathEditField.Visible="on";
                    app.SinexPathEditFieldLabel.Visible="on";

                case 'from Rinex File'
                    run_Control(app)
                    if app.cont_Value==1
                        app.XEditField.Value=app.recPosition1(1,1);
                        app.XEditField.ValueDisplayFormat='%12.4f';
                        app.YEditField.Value=app.recPosition1(2,1);
                        app.YEditField.ValueDisplayFormat='%12.4f';
                        app.ZEditField.Value=app.recPosition1(3,1);
                        app.ZEditField.ValueDisplayFormat='%12.4f';
                    end

                    app.SinexFileButton.Visible='off';
                    app.SinexPathEditField.Visible="off";
                    app.SinexPathEditFieldLabel.Visible="off";

                case 'Manually'
                    app.SinexFileButton.Visible='off';
                    app.SinexPathEditField.Visible="off";
                    app.SinexPathEditFieldLabel.Visible="off";

            end
        end

        % Button pushed function: SinexFileButton
        function SinexFileButtonPushed(app, event)
            s = what('APAS_TR');
            m_prog=s.path;
            m_path=([m_prog '\Inputs\Sinex_File' ]);

            [SinexFilename,Sinexpath,index] = uigetfile({'*' }, ...
                'Select a file',m_path);
            drawnow;
            figure(app.UIFigure)

            if index > 0

                app.SinexPathEditField.Value = 'Reading the Sinex file....Please Wait...';
                app.text1{app.count1,1}= char(sprintf('Reading the Sinex file >>>>>>:' +string(SinexFilename)+'\n'));
                app.StatusTextArea.Value=(app.text1);
                app.count1=app.count1+1;
                drawnow;

                %[app.ground_truth_coor]=read_Sinex(SinexFilename,app.rnxtime(1,4),app.rnxtime(1,2),{app.markerName});
                [app.ground_truth_coor]=read_Sinex2(SinexFilename,app.markerName);

                if ~isempty(app.ground_truth_coor)
                    app.XEditField.Value=app.ground_truth_coor(1,1);
                    app.XEditField.ValueDisplayFormat='%12.4f';
                    app.YEditField.Value=app.ground_truth_coor(2,1);
                    app.YEditField.ValueDisplayFormat='%12.4f';
                    app.ZEditField.Value=app.ground_truth_coor(3,1);
                    app.ZEditField.ValueDisplayFormat='%12.4f';
                else
                    msgbox('The station may not be from the IGS network. Please, enter the station coordinates manually. ')
                    app.XEditField.Value=0;
                    app.XEditField.ValueDisplayFormat='%12.4f';
                    app.YEditField.Value=0;
                    app.YEditField.ValueDisplayFormat='%12.4f';
                    app.ZEditField.Value=0;
                    app.ZEditField.ValueDisplayFormat='%12.4f';

                end


                %   [app.Lat_ground_trut,app.Lon_ground_trut,app.hellp1]=xyz2eby(app.ground_truth_coor);

                app.text1{app.count1,1}=char(sprintf("The Sinex file was read  >>>>>" +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;
                app.SinexPathEditField.Value=string(SinexFilename);
                app.SinexPathEditField.FontSize=10;
            end


        end

        % Button pushed function: TropFileButton
        function TropFileButtonPushed(app, event)

            s = what('APAS_TR');
            m_prog=s.path;
            m_path=([m_prog '\Inputs\Troposphere_Files' ]);

            [TroFilename,Tropath,index] = uigetfile({'*' }, ...
                'Select a file',m_path);
            drawnow;
            figure(app.UIFigure)

            if index > 0

                app.ZTDPathEditField.Value = 'Reading the Troposphere file....Please Wait...';
                app.text1{app.count1,1}= char(sprintf('Reading the Troposphere file >>>>>>:' +string(TroFilename)+'\n'));
                app.StatusTextArea.Value=(app.text1);
                app.count1=app.count1+1;
                drawnow;

                [app.ref_ZTD]=read_Troposphere(TroFilename,app.markerName);


                app.text1{app.count1,1}=char(sprintf("The Troposphere file was read  >>>>>" +"\n"));
                app.StatusTextArea.Value=app.text1;
                app.count1=app.count1+1;
                app.ZTDPathEditField.Value=string(TroFilename);
                app.ZTDPathEditField.FontSize=10;
            end


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)


            app.ground_truth_coor=[app.XEditField.Value;app.YEditField.Value;app.ZEditField.Value];

            [app.Lat_ground_trut,app.Lon_ground_trut,app.hellp1]=xyz2eby(app.ground_truth_coor);


            % app.XEditField.Value=app.ground_truth_coor(1,1);
            % app.XEditField.ValueDisplayFormat='%12.4f';
            % app.YEditField.Value=app.ground_truth_coor(2,1);
            % app.YEditField.ValueDisplayFormat='%12.4f';
            % app.ZEditField.Value=app.ground_truth_coor(3,1);
            % app.ZEditField.ValueDisplayFormat='%12.4f';


            conv_criteria=app.ConvergenceCriteriaEditField.Value;
            delta_time=app.rnxtime(2,1)-app.rnxtime(1,1);

            [rms_values,conv_time,app.north_PPP,app.east_PPP,app.up_PPP]=acc_cal_PPP(app.output,app.ground_truth_coor,app.Lat_ground_trut,app.Lon_ground_trut,delta_time,conv_criteria);


            if ~isnan(conv_time)
                app.NorthRMSEditField.Value=rms_values(1,1);
                app.NorthRMSEditField.ValueDisplayFormat='%6.4f';
                app.EastRMSEditField.Value=rms_values(2,1);
                app.EastRMSEditField.ValueDisplayFormat='%6.4f';
                app.UpRMSEditField.Value=rms_values(3,1);
                app.UpRMSEditField.ValueDisplayFormat='%6.4f';
                app.DRMSEditField.Value=rms_values(4,1);
                app.DRMSEditField.ValueDisplayFormat='%6.4f';
                app.ConvTimeEditField.Value=conv_time;
                app.ConvTimeEditField.ValueDisplayFormat='%5.2f';
            elseif isnan(conv_time)

                msgbox('The solution has not converged. Please check the ground truth coordinates or the solution process.! ');

            end

            trop_PPP=app.output(:,9);
            [ZTD_rms,app.int_ZTD]=trop_rms(app.ref_ZTD,app.rnxtime,trop_PPP);

            app.ZTDRMSEditField.Value=ZTD_rms(1,1);
            app.ZTDRMSEditField.ValueDisplayFormat='%6.4f';


        end

        % Menu selected function: PlottingMenu
        function PlottingMenuSelected(app, event)
            app.TabGroup.Visible = 'on'  ;
            app.TabGroup.SelectedTab = app.PlotTab;
            %app.OpeningPage.Value='' ;

        end

        % Button pushed function: NorthEastUpErrorsButton
        function NorthEastUpErrorsButtonPushed(app, event)

            NEU_plot(app.north_PPP,app.east_PPP,app.up_PPP,app.rnxtime);

        end

        % Button pushed function: HorizontalErrorsButton
        function HorizontalErrorsButtonPushed(app, event)

            horizontal_Errors(app.east_PPP,app.north_PPP);

        end

        % Button pushed function: GPSClockOffsetButton
        function GPSClockOffsetButtonPushed(app, event)

            GPS_clockOffset(app.output,app.rnxtime);

        end

        % Button pushed function: ZenithTroposphericDelayButton
        function ZenithTroposphericDelayButtonPushed(app, event)

            ZTD=app.output(:,9);
            ZTD_Plot(ZTD,app.ref_ZTD,app.int_ZTD,app.rnxtime);

        end

        % Button pushed function: ISBacctoGPSButton
        function ISBacctoGPSButtonPushed(app, event)

            ISB_Plot(app.output,app.combination,app.rnxtime);

        end

        % Menu selected function: AnalysisMenu
        function AnalysisMenuSelected(app, event)
            app.TabGroup.Visible = 'on'  ;
            app.TabGroup.SelectedTab = app.SolAnlysTab;
            %app.OpeningPage.Value='' ;

        end

        % Selection changed function: Residuals
        function ResidualsSelectionChanged(app, event)
            selectedButton = app.Residuals.SelectedObject;
            switch selectedButton.Text

                case 'Residuals for each satellite'
                    app.SystemListBox.Visible="on";
                    app.SystemListBoxLabel.Visible="on";
                    app.PRNEditField.Visible="on";
                    app.PRNEditFieldLabel.Visible="on";
                    app.ObservationListBox.Visible="on";
                    app.ObservationListBoxLabel.Visible="on";
                    app.AcctoListBox.Visible="on";
                    app.AcctoListBoxLabel.Visible="on";

                case 'Code Residuals'
                    app.SystemListBox.Visible="off";
                    app.SystemListBoxLabel.Visible="off";
                    app.PRNEditField.Visible="off";
                    app.PRNEditFieldLabel.Visible="off";
                    app.ObservationListBox.Visible="off";
                    app.ObservationListBoxLabel.Visible="off";
                    app.AcctoListBox.Visible="off";
                    app.AcctoListBoxLabel.Visible="off";

                case 'Phase Residuals'
                    app.SystemListBox.Visible="off";
                    app.SystemListBoxLabel.Visible="off";
                    app.PRNEditField.Visible="off";
                    app.PRNEditFieldLabel.Visible="off";
                    app.ObservationListBox.Visible="off";
                    app.ObservationListBoxLabel.Visible="off";
                    app.AcctoListBox.Visible="off";
                    app.AcctoListBoxLabel.Visible="off";

            end

        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)

           
            codephase=0;

            if app.CodeResidualsButton.Value==1
                obs_type=1; codephase=1;
            elseif app.PhaseResidualsButton.Value==1
                obs_type=2; codephase=1;
            end


            if app.combination==1 && codephase==1

                resplotting(app.GPS_output,obs_type,'G',app.rnxtime);

            elseif app.combination==2 && codephase==1

                resplotting(app.GPS_output,obs_type,'G',app.rnxtime);
                resplotting(app.GLO_output,obs_type,'R',app.rnxtime);

            elseif app.combination==3 && codephase==1

                resplotting(app.GPS_output,obs_type,'G',app.rnxtime);
                resplotting(app.GAL_output,obs_type,'E',app.rnxtime);

            elseif app.combination==4 && codephase==1

                resplotting(app.GPS_output,obs_type,'G',app.rnxtime);
                resplotting(app.GLO_output,obs_type,'R',app.rnxtime);
                resplotting(app.GAL_output,obs_type,'E',app.rnxtime);

            elseif app.combination==5 && codephase==1

                resplotting(app.GPS_output,obs_type,'G',app.rnxtime);
                resplotting(app.GLO_output,obs_type,'R',app.rnxtime);
                resplotting(app.GAL_output,obs_type,'E',app.rnxtime);
                resplotting(app.BDS2_output,obs_type,'C2-',app.rnxtime);

            elseif app.combination==6 && codephase==1

                resplotting(app.GPS_output,obs_type,'G',app.rnxtime);
                resplotting(app.GLO_output,obs_type,'R',app.rnxtime);
                resplotting(app.GAL_output,obs_type,'E',app.rnxtime);
                resplotting(app.BDS3_output,obs_type,'C3-',app.rnxtime);

            elseif app.combination==7 && codephase==1

                resplotting(app.GPS_output,obs_type,'G',app.rnxtime);
                resplotting(app.GLO_output,obs_type,'R',app.rnxtime);
                resplotting(app.GAL_output,obs_type,'E',app.rnxtime);
                resplotting(app.BDS2_output,obs_type,'C2-',app.rnxtime);
                resplotting(app.BDS3_output,obs_type,'C3-',app.rnxtime);

            elseif app.combination==8 && codephase==1

                resplotting(app.GPS_output,obs_type,'G',app.rnxtime);
                resplotting(app.GLO_output,obs_type,'R',app.rnxtime);
                resplotting(app.GAL_output,obs_type,'E',app.rnxtime);
                resplotting(app.BDS2_output,obs_type,'C2-',app.rnxtime);
                resplotting(app.BDS3_output,obs_type,'C3-',app.rnxtime);
                resplotting(app.QZSS_output,obs_type,'J',app.rnxtime);

            end


            if app.ResidualsforeachsatelliteButton.Value==1

                if app.SystemListBox.Value=="GPS"; system_output=app.GPS_output;
                elseif app.SystemListBox.Value=="GLONASS"; system_output=app.GLO_output;
                elseif app.SystemListBox.Value=="Galileo"; system_output=app.GAL_output;
                elseif app.SystemListBox.Value=="BDS-2"; system_output=app.BDS2_output;
                elseif app.SystemListBox.Value=="BDS-3"; system_output=app.BDS3_output;
                elseif app.SystemListBox.Value=="QZSS"; system_output=app.QZSS_output;
                end

                satNum=app.PRNEditField.Value;

                if app.ObservationListBox.Value=="Code"; observation=1;
                elseif app.ObservationListBox.Value=="Phase"; observation=2;  end

                if app.AcctoListBox.Value=="Elevation Angle"; Accto=1;
                elseif app.AcctoListBox.Value=="C/N0-freq.1"; Accto=2;
                elseif app.AcctoListBox.Value=="C/N0-freq.2"; Accto=3;
                end
                %system_output.code_residual(:,2)

                if isempty(system_output)
                    msgbox('This navigation system was not selected in the processing step..')

                elseif satNum>size(system_output.elevation,2)
                    msgbox('Unused Satellite PRN..')
                else

                    each_satRes(system_output,satNum,observation,Accto,app.rnxtime);
                end
            end
        end

        % Button pushed function: GDOPandPDOPValuesButton
        function GDOPandPDOPValuesButtonPushed(app, event)

            DOP_values_plot(app.combination,app.DOP_values,app.rnxtime);

        end

        % Button pushed function: SatelliteNumbersButton
        function SatelliteNumbersButtonPushed(app, event)

            visible_sat_Plot(app.combination,app.GPS_output,app.GLO_output,app.GAL_output,app.BDS2_output,app.BDS3_output,app.QZSS_output,app.rnxtime);

        end

        % Button pushed function: SatelliteVisibilityChartButton
        function SatelliteVisibilityChartButtonPushed(app, event)

            plotSat_Chart(app.combination,app.GPS_output,app.GLO_output,app.GAL_output,app.BDS2_output,app.BDS3_output,app.QZSS_output,app.rnxtime);

        end

        % Button pushed function: CN0AnalysisButton
        function CN0AnalysisButtonPushed(app, event)

            CN0_analyze(app.combination,app.GPS_output,app.GLO_output,app.GAL_output,app.BDS2_output,app.BDS3_output,app.QZSS_output);

        end

        % Button pushed function: SkyPlotAnalysisButton
        function SkyPlotAnalysisButtonPushed(app, event)

            skyPlotAnalysis(app.combination,app.GPS_output,app.GLO_output,app.GAL_output,app.BDS2_output,app.BDS3_output,app.QZSS_output);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputsMenu = uimenu(app.UIFigure);
            app.InputsMenu.Text = 'Inputs';
            app.RinexDataMenu = uimenu(app.InputsMenu);
            app.RinexDataMenu.MenuSelectedFcn = createCallbackFcn(app, @RinexDataMenuSelected, true);
            app.RinexDataMenu.Text = 'Rinex Data';
            app.OrbitandClockProductsMenu = uimenu(app.InputsMenu);
            app.OrbitandClockProductsMenu.MenuSelectedFcn = createCallbackFcn(app, @OrbitandClockProductsMenuSelected, true);
            app.OrbitandClockProductsMenu.Text = 'Orbit and Clock Products';
            app.AntennaandDCBFilesMenu = uimenu(app.InputsMenu);
            app.AntennaandDCBFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @AntennaandDCBFilesMenuSelected, true);
            app.AntennaandDCBFilesMenu.Text = 'Antenna and DCB Files';
            app.ModelsMenu = uimenu(app.UIFigure);
            app.ModelsMenu.Text = 'Models';
            app.SatelliteandReceiverDependentMenu = uimenu(app.ModelsMenu);
            app.SatelliteandReceiverDependentMenu.MenuSelectedFcn = createCallbackFcn(app, @SatelliteandReceiverDependentMenuSelected, true);
            app.SatelliteandReceiverDependentMenu.Text = 'Satellite and Receiver Dependent';
            app.AtmosphereMenu = uimenu(app.ModelsMenu);
            app.AtmosphereMenu.MenuSelectedFcn = createCallbackFcn(app, @AtmosphereMenuSelected, true);
            app.AtmosphereMenu.Text = 'Atmosphere';
            app.FilteringMenu = uimenu(app.UIFigure);
            app.FilteringMenu.Text = 'Filtering';
            app.AdjustmentSettingsMenu = uimenu(app.FilteringMenu);
            app.AdjustmentSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustmentSettingsMenuSelected, true);
            app.AdjustmentSettingsMenu.Text = 'Adjustment Settings';
            app.OutputsMenu = uimenu(app.UIFigure);
            app.OutputsMenu.Text = 'Outputs';
            app.AnalysisMenu = uimenu(app.OutputsMenu);
            app.AnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisMenuSelected, true);
            app.AnalysisMenu.Text = 'Analysis';
            app.PlottingMenu = uimenu(app.OutputsMenu);
            app.PlottingMenu.MenuSelectedFcn = createCallbackFcn(app, @PlottingMenuSelected, true);
            app.PlottingMenu.Text = 'Plotting';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RinexTab = uitab(app.TabGroup);
            app.RinexTab.Title = 'Rinex';
            app.RinexFileEditFieldLabel = uilabel(app.RinexTab);
            app.RinexFileEditFieldLabel.Text = 'Rinex File:';
            app.RinexFileEditField = uieditfield(app.RinexTab, 'text');
            app.RinexFileButton = uibutton(app.RinexTab, 'push');
            app.RinexFileButton.ButtonPushedFcn = createCallbackFcn(app, @RinexFileButtonPushed, true);
            app.RinexFileButton.Icon = fullfile(pathToMLAPP, 'Software_Icons', 'Klasor.png');
            app.RinexFileButton.Text = 'Rinex File ';
            app.SignalTypesLabel = uilabel(app.RinexTab);
            app.SignalTypesLabel.Text = 'Signal Types';
            app.EF_G = uieditfield(app.RinexTab, 'text');
            app.EF_R = uieditfield(app.RinexTab, 'text');
            app.EF_E = uieditfield(app.RinexTab, 'text');
            app.EF_C2 = uieditfield(app.RinexTab, 'text');
            app.EF_C3 = uieditfield(app.RinexTab, 'text');
            app.EF_J = uieditfield(app.RinexTab, 'text');
            app.PPPCombinationsPanel = uipanel(app.RinexTab);
            app.PPPCombinationsPanel.Title = 'PPP Combinations';
            app.ButtonGroup_4 = uibuttongroup(app.PPPCombinationsPanel);
            app.GButton = uiradiobutton(app.ButtonGroup_4);
            app.GButton.Text = 'G';
            app.GButton.Value = true;
            app.GRButton = uiradiobutton(app.ButtonGroup_4);
            app.GRButton.Text = 'GR';
            app.GEButton = uiradiobutton(app.ButtonGroup_4);
            app.GEButton.Text = 'GE';
            app.GREButton = uiradiobutton(app.ButtonGroup_4);
            app.GREButton.Text = 'GRE';
            app.GREC2Button = uiradiobutton(app.ButtonGroup_4);
            app.GREC2Button.Text = 'GREC2';
            app.GREC3Button = uiradiobutton(app.ButtonGroup_4);
            app.GREC3Button.Text = 'GREC3';
            app.GREC2C3JButton = uiradiobutton(app.ButtonGroup_4);
            app.GREC2C3JButton.Text = 'GREC2C3J';
            app.GREC2C3Button = uiradiobutton(app.ButtonGroup_4);
            app.GREC2C3Button.Text = 'GREC2C3';
            app.GNSSObservationRinexDataLabel = uilabel(app.RinexTab);
            app.GNSSObservationRinexDataLabel.Text = 'GNSS Observation (Rinex) Data';
            app.GPSLabel = uilabel(app.RinexTab);
            app.GPSLabel.Text = 'GPS';
            app.GLONASSLabel = uilabel(app.RinexTab);
            app.GLONASSLabel.Text = 'GLONASS';
            app.GalileoLabel = uilabel(app.RinexTab);
            app.GalileoLabel.Text = 'Galileo';
            app.BDS2Label = uilabel(app.RinexTab);
            app.BDS2Label.Text = 'BDS-2';
            app.BDS3Label = uilabel(app.RinexTab);
            app.BDS3Label.Text = 'BDS-3';
            app.QZSSLabel = uilabel(app.RinexTab);
            app.QZSSLabel.Text = 'QZSS';
            app.OrbClkTab = uitab(app.TabGroup);
            app.OrbClkTab.Title = 'OrbClk';
            app.OrbitFileEditFieldLabel = uilabel(app.OrbClkTab);
            app.OrbitFileEditFieldLabel.Text = 'Orbit File:';
            app.OrbitFileEditField = uieditfield(app.OrbClkTab, 'text');
            app.OrbitFileButton = uibutton(app.OrbClkTab, 'push');
            app.OrbitFileButton.ButtonPushedFcn = createCallbackFcn(app, @OrbitFileButtonPushed, true);
            app.OrbitFileButton.Icon = fullfile(pathToMLAPP, 'Software_Icons', 'Klasor.png');
            app.OrbitFileButton.Text = 'Orbit File ';
            app.ClockFileEditFieldLabel = uilabel(app.OrbClkTab);
            app.ClockFileEditFieldLabel.Text = 'Clock File:';
            app.ClockFileEditField = uieditfield(app.OrbClkTab, 'text');
            app.ClockFileButton = uibutton(app.OrbClkTab, 'push');
            app.ClockFileButton.ButtonPushedFcn = createCallbackFcn(app, @ClockFileButtonPushed, true);
            app.ClockFileButton.Icon = fullfile(pathToMLAPP, 'Software_Icons', 'Klasor.png');
            app.ClockFileButton.Text = 'Clock File ';
            app.SatelliteOrbitSP3ProductLabel = uilabel(app.OrbClkTab);
            app.SatelliteOrbitSP3ProductLabel.Text = 'Satellite Orbit (SP3) Product';
            app.SatelliteClockCLKProductLabel = uilabel(app.OrbClkTab);
            app.SatelliteClockCLKProductLabel.Text = 'Satellite Clock (CLK) Product';
            app.AntexTab = uitab(app.TabGroup);
            app.AntexTab.Title = 'Antex';
            app.AntennaFileEditFieldLabel = uilabel(app.AntexTab);
            app.AntennaFileEditFieldLabel.Text = 'Antenna File:';
            app.AntennaFileEditField = uieditfield(app.AntexTab, 'text');
            app.AntexFileButton = uibutton(app.AntexTab, 'push');
            app.AntexFileButton.ButtonPushedFcn = createCallbackFcn(app, @AntexFileButtonPushed, true);
            app.AntexFileButton.Icon = fullfile(pathToMLAPP, 'Software_Icons', 'Klasor.png');
            app.AntexFileButton.Text = 'Antex File ';
            app.DCBFileButton = uibutton(app.AntexTab, 'push');
            app.DCBFileButton.ButtonPushedFcn = createCallbackFcn(app, @DCBFileButtonPushed, true);
            app.DCBFileButton.Icon = fullfile(pathToMLAPP, 'Software_Icons', 'Klasor.png');
            app.DCBFileButton.Text = 'DCB File ';
            app.AntennaATXLabel = uilabel(app.AntexTab);
            app.AntennaATXLabel.Text = 'Antenna (ATX) ';
            app.DifferentialCodeBiasDCBOptionalLabel = uilabel(app.AntexTab);
            app.DifferentialCodeBiasDCBOptionalLabel.Text = 'Differential Code Bias (DCB) - Optional*';
            app.DCBFileEditFieldLabel = uilabel(app.AntexTab);
            app.DCBFileEditFieldLabel.Text = 'DCB File:';
            app.DCBFileEditField = uieditfield(app.AntexTab, 'text');
            app.M1Tab = uitab(app.TabGroup);
            app.M1Tab.Title = 'M1';
            app.SatelliteDependentErrorSourcesPanel = uipanel(app.M1Tab);
            app.SatelliteDependentErrorSourcesPanel.Title = 'Satellite Dependent Error Sources';
            app.ReceiverDependentandOtherErrorSourcesPanel = uipanel(app.M1Tab);
            app.ReceiverDependentandOtherErrorSourcesPanel.Title = 'Receiver Dependent and Other Error Sources';
            app.SatellitePhaseCenterOffsetCheckBox = uicheckbox(app.M1Tab);
            app.SatellitePhaseCenterOffsetCheckBox.Text = 'Satellite Phase Center Offset';
            app.ReceiverPhaseCenterOffsetCheckBox = uicheckbox(app.M1Tab);
            app.ReceiverPhaseCenterOffsetCheckBox.Text = 'Receiver Phase Center Offset';
            app.SatelliteWindupEffectCheckBox = uicheckbox(app.M1Tab);
            app.SatelliteWindupEffectCheckBox.Text = 'Satellite Wind-up Effect';
            app.ReceiverPhaseCenterVariationCheckBox = uicheckbox(app.M1Tab);
            app.ReceiverPhaseCenterVariationCheckBox.Text = 'Receiver Phase Center Variation';
            app.SatelliteEclipseConditionCheckBox = uicheckbox(app.M1Tab);
            app.SatelliteEclipseConditionCheckBox.Text = 'Satellite Eclipse Condition';
            app.ReceiverAntennaReferencePointCheckBox = uicheckbox(app.M1Tab);
            app.ReceiverAntennaReferencePointCheckBox.Text = 'Receiver Antenna Reference Point';
            app.ReceiverClockJumpDetectionCheckBox = uicheckbox(app.M1Tab);
            app.ReceiverClockJumpDetectionCheckBox.Text = 'Receiver Clock Jump Detection';
            app.SolidTideCheckBox = uicheckbox(app.M1Tab);
            app.SolidTideCheckBox.Text = 'Solid Tide ';
            app.SatelliteElevationCutOffLabel = uilabel(app.M1Tab);
            app.SatelliteElevationCutOffLabel.Text = 'Satellite Elevation Cut-Off:';
            app.SatelliteElevationCutOffEditField = uieditfield(app.M1Tab, 'numeric');
            app.CycleSlipDetectionAlgorithmButtonGroup = uibuttongroup(app.M1Tab);
            app.CycleSlipDetectionAlgorithmButtonGroup.Title = 'Cycle Slip Detection Algorithm';
            app.ROTIbasedGFCombinationFBMWAMWCombinationButton = uiradiobutton(app.CycleSlipDetectionAlgorithmButtonGroup);
            app.ROTIbasedGFCombinationFBMWAMWCombinationButton.Text = 'ROTI-based GF Combination + FBMWA MW Combination  ';
            app.ROTIbasedGFCombinationFBMWAMWCombinationButton.Value = true;
            app.TurboEditAlgorithmButton = uiradiobutton(app.CycleSlipDetectionAlgorithmButtonGroup);
            app.TurboEditAlgorithmButton.Text = 'TurboEdit Algorithm';
            app.SatelliteCN0CutOffLabel = uilabel(app.M1Tab);
            app.SatelliteCN0CutOffLabel.Text = 'Satellite C/N0 Cut-Off:';
            app.SatelliteCN0CutOffEditField = uieditfield(app.M1Tab, 'numeric');
            app.UseSmoothCodeMeasurementsCheckBox = uicheckbox(app.M1Tab);
            app.UseSmoothCodeMeasurementsCheckBox.Text = 'Use Smooth Code Measurements';
            app.Label = uilabel(app.M1Tab);
            app.Label.Text = '°';
            app.dBHzLabel = uilabel(app.M1Tab);
            app.dBHzLabel.Text = 'dB-Hz';
            app.M2Tab = uitab(app.TabGroup);
            app.M2Tab.Title = 'M2';
            app.TroposphericModelingPanel = uipanel(app.M2Tab);
            app.TroposphericModelingPanel.Title = 'Tropospheric Modeling';
            app.MappingFunctionWetLabel_3 = uilabel(app.TroposphericModelingPanel);
            app.MappingFunctionWetLabel_3.Text = 'Mapping Function (Wet)';
            app.VMF3Label_9 = uilabel(app.TroposphericModelingPanel);
            app.VMF3Label_9.Text = 'VMF3';
            app.VMF1Label_9 = uilabel(app.TroposphericModelingPanel);
            app.VMF1Label_9.Text = 'VMF1';
            app.VMF3GPT3Label_5 = uilabel(app.TroposphericModelingPanel);
            app.VMF3GPT3Label_5.Text = 'VMF3 (GPT3)';
            app.VMF1GPT2Label_5 = uilabel(app.TroposphericModelingPanel);
            app.VMF1GPT2Label_5.Text = 'VMF1 (GPT2)';
            app.NMFLabel_5 = uilabel(app.TroposphericModelingPanel);
            app.NMFLabel_5.Text = 'NMF';
            app.VMF3Label_10 = uilabel(app.TroposphericModelingPanel);
            app.VMF3Label_10.Text = 'VMF3';
            app.VMF1Label_10 = uilabel(app.TroposphericModelingPanel);
            app.VMF1Label_10.Text = 'VMF1';
            app.VMF3GPT3Label_6 = uilabel(app.TroposphericModelingPanel);
            app.VMF3GPT3Label_6.Text = 'VMF3 (GPT3)';
            app.VMF1GPT2Label_6 = uilabel(app.TroposphericModelingPanel);
            app.VMF1GPT2Label_6.Text = 'VMF1 (GPT2)';
            app.NMFLabel_6 = uilabel(app.TroposphericModelingPanel);
            app.NMFLabel_6.Text = 'NMF';
            app.MappingFunctionDryLabel = uilabel(app.TroposphericModelingPanel);
            app.MappingFunctionDryLabel.Text = 'Mapping Function (Dry)';
            app.ZenithDelayWetLabel = uilabel(app.TroposphericModelingPanel);
            app.ZenithDelayWetLabel.Text = 'Zenith Delay (Wet)';
            app.VMF3Label_11 = uilabel(app.TroposphericModelingPanel);
            app.VMF3Label_11.Text = 'VMF3';
            app.VMF1Label_11 = uilabel(app.TroposphericModelingPanel);
            app.VMF1Label_11.Text = 'VMF1';
            app.GPT3AskneLabel = uilabel(app.TroposphericModelingPanel);
            app.GPT3AskneLabel.Text = 'GPT3+Askne';
            app.GPT2AskneLabel = uilabel(app.TroposphericModelingPanel);
            app.GPT2AskneLabel.Text = 'GPT2+Askne';
            app.UNB3Label_5 = uilabel(app.TroposphericModelingPanel);
            app.UNB3Label_5.Text = 'UNB3';
            app.ButtonGroup_2 = uibuttongroup(app.TroposphericModelingPanel);
            app.VMF3 = uiradiobutton(app.ButtonGroup_2);
            app.VMF3.Text = '';
            app.VMF3.Value = true;
            app.VMF1 = uiradiobutton(app.ButtonGroup_2);
            app.VMF1.Text = '';
            app.GPT3 = uiradiobutton(app.ButtonGroup_2);
            app.GPT3.Text = '';
            app.GPT2 = uiradiobutton(app.ButtonGroup_2);
            app.GPT2.Text = '';
            app.NMF = uiradiobutton(app.ButtonGroup_2);
            app.NMF.Text = '';
            app.UNB3Label_6 = uilabel(app.TroposphericModelingPanel);
            app.UNB3Label_6.Text = 'UNB3';
            app.GPT2SaastomienLabel = uilabel(app.TroposphericModelingPanel);
            app.GPT2SaastomienLabel.Text = 'GPT2+Saastomien';
            app.GPT3SaastomienLabel = uilabel(app.TroposphericModelingPanel);
            app.GPT3SaastomienLabel.Text = 'GPT3+Saastomien';
            app.VMF1Label_12 = uilabel(app.TroposphericModelingPanel);
            app.VMF1Label_12.Text = 'VMF1';
            app.VMF3Label_12 = uilabel(app.TroposphericModelingPanel);
            app.VMF3Label_12.Text = 'VMF3';
            app.ZenithDelayDryLabel = uilabel(app.TroposphericModelingPanel);
            app.ZenithDelayDryLabel.Text = 'Zenith Delay (Dry)';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.FilteringParametersPanel = uipanel(app.FilterTab);
            app.FilteringParametersPanel.Title = 'Filtering Parameters';
            app.CoordinatesLabel = uilabel(app.FilteringParametersPanel);
            app.CoordinatesLabel.Text = 'Coordinates:';
            app.CoordinatesEditField = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.CoordinatesEditField.ValueDisplayFormat = '%12.0f';
            app.CoordinatesNoise = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.CoordinatesNoise.ValueDisplayFormat = '%10.0f';
            app.GPSReceiverClockNoise = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.GPSReceiverClockNoise.ValueDisplayFormat = '%12.0f';
            app.GPSReceiverClockLabel = uilabel(app.FilteringParametersPanel);
            app.GPSReceiverClockLabel.Text = 'GPS Receiver Clock:';
            app.GPSReceiverClockEditField = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.GPSReceiverClockEditField.ValueDisplayFormat = '%12.0f';
            app.ZenithWetDelayNoise = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.ZenithWetDelayNoise.ValueDisplayFormat = '%2.2f';
            app.ZenithWetDelayLabel_4 = uilabel(app.FilteringParametersPanel);
            app.ZenithWetDelayLabel_4.Text = 'Zenith Wet Delay:';
            app.ZenithWetDelayEditField = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.ZenithWetDelayEditField.ValueDisplayFormat = '%2.2f';
            app.InterSystemBiasesNoise = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.InterSystemBiasesNoise.ValueDisplayFormat = '%12.0f';
            app.InterSystemBiasesLabel = uilabel(app.FilteringParametersPanel);
            app.InterSystemBiasesLabel.Text = 'Inter System Biases:';
            app.InterSystemBiasesEditField = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.InterSystemBiasesEditField.ValueDisplayFormat = '%12.0f';
            app.FloatAmbiguitiesNoise = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.FloatAmbiguitiesNoise.ValueDisplayFormat = '%2.0f';
            app.FloatAmbigutiesLabel = uilabel(app.FilteringParametersPanel);
            app.FloatAmbigutiesLabel.Text = 'Float Ambiguities:';
            app.FloatAmbiguitiesEditField = uieditfield(app.FilteringParametersPanel, 'numeric');
            app.FloatAmbiguitiesEditField.ValueDisplayFormat = '%3.0f';
            app.InitialStandardDeviationLabel = uilabel(app.FilteringParametersPanel);
            app.InitialStandardDeviationLabel.Text = 'Initial Standard Deviation ';
            app.SystemNoiseLabel = uilabel(app.FilteringParametersPanel);
            app.SystemNoiseLabel.Text = 'System Noise';
            app.mhLabel = uilabel(app.FilteringParametersPanel);
            app.mhLabel.Text = 'm/√h';
            app.mhLabel_2 = uilabel(app.FilteringParametersPanel);
            app.mhLabel_2.Text = 'm/√h';
            app.mhLabel_3 = uilabel(app.FilteringParametersPanel);
            app.mhLabel_3.Text = 'm/√h';
            app.mhLabel_4 = uilabel(app.FilteringParametersPanel);
            app.mhLabel_4.Text = 'm/√h';
            app.mhLabel_5 = uilabel(app.FilteringParametersPanel);
            app.mhLabel_5.Text = 'm/√h';
            app.mLabel_6 = uilabel(app.FilteringParametersPanel);
            app.mLabel_6.Text = 'm';
            app.mLabel_7 = uilabel(app.FilteringParametersPanel);
            app.mLabel_7.Text = 'm';
            app.mLabel_8 = uilabel(app.FilteringParametersPanel);
            app.mLabel_8.Text = 'm';
            app.mLabel_9 = uilabel(app.FilteringParametersPanel);
            app.mLabel_9.Text = 'm';
            app.mLabel_10 = uilabel(app.FilteringParametersPanel);
            app.mLabel_10.Text = 'm';
            app.WeightingOptionsPanel = uipanel(app.FilterTab);
            app.WeightingOptionsPanel.Title = 'Weighting Options';
            app.ButtonGroup_3 = uibuttongroup(app.WeightingOptionsPanel);
            app.ElevationAngleDependentButton = uiradiobutton(app.ButtonGroup_3);
            app.ElevationAngleDependentButton.Text = 'Elevation Angle Dependent';
            app.ElevationAngleDependentButton.Value = true;
            app.CN0DependentButton = uiradiobutton(app.ButtonGroup_3);
            app.CN0DependentButton.Text = 'C/N0 Dependent';
            app.ElevationAngleCN0DependentButton = uiradiobutton(app.ButtonGroup_3);
            app.ElevationAngleCN0DependentButton.Text = 'Elevation Angle + C/N0 Dependent';
            app.StandardDeviationofmeasurementsPanel = uipanel(app.FilterTab);
            app.StandardDeviationofmeasurementsPanel.Title = 'Standard Deviation of measurements (σ)';
            app.CodeLabel = uilabel(app.StandardDeviationofmeasurementsPanel);
            app.CodeLabel.Text = 'Code:';
            app.CodeEditField = uieditfield(app.StandardDeviationofmeasurementsPanel, 'numeric');
            app.CodeEditField.ValueDisplayFormat = '%2.3f';
            app.PhaseLabel = uilabel(app.StandardDeviationofmeasurementsPanel);
            app.PhaseLabel.Text = 'Phase:';
            app.PhaseEditField = uieditfield(app.StandardDeviationofmeasurementsPanel, 'numeric');
            app.PhaseEditField.ValueDisplayFormat = '%2.3f';
            app.mLabel_11 = uilabel(app.StandardDeviationofmeasurementsPanel);
            app.mLabel_11.Text = 'm';
            app.mLabel_12 = uilabel(app.StandardDeviationofmeasurementsPanel);
            app.mLabel_12.Text = 'm';
            app.SettingsofratiosforMultiGNSSPanel = uipanel(app.FilterTab);
            app.SettingsofratiosforMultiGNSSPanel.Title = 'Settings of σ ratios for Multi-GNSS ';
            app.Code_GPS_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Code_GPS_weight.ValueDisplayFormat = '%1.1f';
            app.Code_GLO_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Code_GLO_weight.ValueDisplayFormat = '%1.1f';
            app.Code_GAL_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Code_GAL_weight.ValueDisplayFormat = '%1.1f';
            app.Code_Cmeo_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Code_Cmeo_weight.ValueDisplayFormat = '%1.1f';
            app.Code_Cgeo_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Code_Cgeo_weight.ValueDisplayFormat = '%1.1f';
            app.Code_QZSS_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Code_QZSS_weight.ValueDisplayFormat = '%1.1f';
            app.Phase_GPS_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Phase_GPS_weight.ValueDisplayFormat = '%1.1f';
            app.Phase_GLO_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Phase_GLO_weight.ValueDisplayFormat = '%1.1f';
            app.Phase_GAL_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Phase_GAL_weight.ValueDisplayFormat = '%1.1f';
            app.Phase_Cmeo_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Phase_Cmeo_weight.ValueDisplayFormat = '%1.1f';
            app.Phase_Cgeo_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Phase_Cgeo_weight.ValueDisplayFormat = '%1.1f';
            app.Phase_QZSS_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Phase_QZSS_weight.ValueDisplayFormat = '%1.1f';
            app.CodeEditFieldLabel_2 = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.CodeEditFieldLabel_2.Text = 'Code:';
            app.PhaseEditFieldLabel_2 = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.PhaseEditFieldLabel_2.Text = 'Phase:';
            app.GLabel = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.GLabel.Text = 'G';
            app.RLabel = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.RLabel.Text = 'R';
            app.ELabel = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.ELabel.Text = 'E';
            app.CmLabel = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.CmLabel.Text = 'C-m';
            app.CgLabel = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.CgLabel.Text = 'C-g';
            app.JLabel = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.JLabel.Text = 'J';
            app.Code_Cigso_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Code_Cigso_weight.ValueDisplayFormat = '%1.1f';
            app.Phase_Cigso_weight = uieditfield(app.SettingsofratiosforMultiGNSSPanel, 'numeric');
            app.Phase_Cigso_weight.ValueDisplayFormat = '%1.1f';
            app.CiLabel = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.CiLabel.Text = 'C-i';
            app.C_mBDSMEOC_iBDSIGSOC_gBDSGEOLabel = uilabel(app.SettingsofratiosforMultiGNSSPanel);
            app.C_mBDSMEOC_iBDSIGSOC_gBDSGEOLabel.Text = '(C_m=BDS-MEO, C_i=BDS-IGSO, C_g=BDS-GEO)';
            app.SolAnlysTab = uitab(app.TabGroup);
            app.SolAnlysTab.Title = 'SolAnlys';
            app.GroundTruthCoordinatesButtonGroup = uibuttongroup(app.SolAnlysTab);
            app.GroundTruthCoordinatesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GroundTruthCoordinatesButtonGroupSelectionChanged, true);
            app.GroundTruthCoordinatesButtonGroup.Title = 'Ground Truth Coordinates';
            app.ManuallyButton = uiradiobutton(app.GroundTruthCoordinatesButtonGroup);
            app.ManuallyButton.Text = 'Manually';
            app.ManuallyButton.Value = true;
            app.fromSinexFileButton = uiradiobutton(app.GroundTruthCoordinatesButtonGroup);
            app.fromSinexFileButton.Text = 'from Sinex File';
            app.XEditFieldLabel = uilabel(app.GroundTruthCoordinatesButtonGroup);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.GroundTruthCoordinatesButtonGroup, 'numeric');
            app.XEditField.ValueDisplayFormat = '%12.4f';
            app.YEditFieldLabel = uilabel(app.GroundTruthCoordinatesButtonGroup);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.GroundTruthCoordinatesButtonGroup, 'numeric');
            app.YEditField.ValueDisplayFormat = '%12.4f';
            app.ZEditFieldLabel = uilabel(app.GroundTruthCoordinatesButtonGroup);
            app.ZEditFieldLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.GroundTruthCoordinatesButtonGroup, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%12.4f';
            app.SinexFileButton = uibutton(app.GroundTruthCoordinatesButtonGroup, 'push');
            app.SinexFileButton.ButtonPushedFcn = createCallbackFcn(app, @SinexFileButtonPushed, true);
            app.SinexFileButton.Icon = fullfile(pathToMLAPP, 'Software_Icons', 'Klasor.png');
            app.SinexFileButton.Text = 'Sinex File ';
            app.fromRinexFileButton = uiradiobutton(app.GroundTruthCoordinatesButtonGroup);
            app.fromRinexFileButton.Text = 'from Rinex File';
            app.mLabel = uilabel(app.GroundTruthCoordinatesButtonGroup);
            app.mLabel.Text = 'm';
            app.SinexPathEditField = uieditfield(app.GroundTruthCoordinatesButtonGroup, 'text');
            app.SinexPathEditFieldLabel = uilabel(app.GroundTruthCoordinatesButtonGroup);
            app.SinexPathEditFieldLabel.Text = 'Sinex Path:';
            app.mLabel_17 = uilabel(app.GroundTruthCoordinatesButtonGroup);
            app.mLabel_17.Text = 'm';
            app.mLabel_18 = uilabel(app.GroundTruthCoordinatesButtonGroup);
            app.mLabel_18.Text = 'm';
            app.ConvergenceCriteriaLabel = uilabel(app.SolAnlysTab);
            app.ConvergenceCriteriaLabel.Text = 'Convergence Criteria:';
            app.ConvergenceCriteriaEditField = uieditfield(app.SolAnlysTab, 'numeric');
            app.NorthRMSEditFieldLabel = uilabel(app.SolAnlysTab);
            app.NorthRMSEditFieldLabel.Text = 'North RMS:';
            app.NorthRMSEditField = uieditfield(app.SolAnlysTab, 'numeric');
            app.EastRMSEditFieldLabel = uilabel(app.SolAnlysTab);
            app.EastRMSEditFieldLabel.Text = 'East RMS:';
            app.EastRMSEditField = uieditfield(app.SolAnlysTab, 'numeric');
            app.UpRMSEditFieldLabel = uilabel(app.SolAnlysTab);
            app.UpRMSEditFieldLabel.Text = 'Up RMS:';
            app.UpRMSEditField = uieditfield(app.SolAnlysTab, 'numeric');
            app.DRMSEditFieldLabel = uilabel(app.SolAnlysTab);
            app.DRMSEditFieldLabel.Text = '3D RMS:';
            app.DRMSEditField = uieditfield(app.SolAnlysTab, 'numeric');
            app.ConvTimeEditFieldLabel = uilabel(app.SolAnlysTab);
            app.ConvTimeEditFieldLabel.Text = 'Conv. Time:';
            app.ConvTimeEditField = uieditfield(app.SolAnlysTab, 'numeric');
            app.CalculateButton = uibutton(app.SolAnlysTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Combination = uieditfield(app.SolAnlysTab, 'text');
            app.mLabel_4 = uilabel(app.SolAnlysTab);
            app.mLabel_4.Text = 'm';
            app.mLabel_5 = uilabel(app.SolAnlysTab);
            app.mLabel_5.Text = 'm';
            app.GroundTruthZenithTroposphereDelayOptionalPanel = uipanel(app.SolAnlysTab);
            app.GroundTruthZenithTroposphereDelayOptionalPanel.Title = 'Ground Truth Zenith Troposphere Delay (Optional)';
            app.TropFileButton = uibutton(app.GroundTruthZenithTroposphereDelayOptionalPanel, 'push');
            app.TropFileButton.ButtonPushedFcn = createCallbackFcn(app, @TropFileButtonPushed, true);
            app.TropFileButton.Icon = fullfile(pathToMLAPP, 'Software_Icons', 'Klasor.png');
            app.TropFileButton.Text = 'Trop File ';
            app.ZTDPathEditField = uieditfield(app.GroundTruthZenithTroposphereDelayOptionalPanel, 'text');
            app.ZTDPathEditFieldLabel = uilabel(app.GroundTruthZenithTroposphereDelayOptionalPanel);
            app.ZTDPathEditFieldLabel.Text = 'ZTD Path:';
            app.mLabel_13 = uilabel(app.SolAnlysTab);
            app.mLabel_13.Text = 'm';
            app.ZTDRMSEditFieldLabel = uilabel(app.SolAnlysTab);
            app.ZTDRMSEditFieldLabel.Text = 'ZTD RMS:';
            app.ZTDRMSEditField = uieditfield(app.SolAnlysTab, 'numeric');
            app.minLabel = uilabel(app.SolAnlysTab);
            app.minLabel.Text = 'min.';
            app.mLabel_14 = uilabel(app.SolAnlysTab);
            app.mLabel_14.Text = 'm';
            app.mLabel_15 = uilabel(app.SolAnlysTab);
            app.mLabel_15.Text = 'm';
            app.mLabel_16 = uilabel(app.SolAnlysTab);
            app.mLabel_16.Text = 'm';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.NorthEastUpErrorsButton = uibutton(app.PlotTab, 'push');
            app.NorthEastUpErrorsButton.ButtonPushedFcn = createCallbackFcn(app, @NorthEastUpErrorsButtonPushed, true);
            app.NorthEastUpErrorsButton.Text = 'North, East, Up Errors';
            app.GPSClockOffsetButton = uibutton(app.PlotTab, 'push');
            app.GPSClockOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @GPSClockOffsetButtonPushed, true);
            app.GPSClockOffsetButton.Text = 'GPS Clock Offset';
            app.ISBacctoGPSButton = uibutton(app.PlotTab, 'push');
            app.ISBacctoGPSButton.ButtonPushedFcn = createCallbackFcn(app, @ISBacctoGPSButtonPushed, true);
            app.ISBacctoGPSButton.Text = 'ISB (acc. to GPS)';
            app.ZenithTroposphericDelayButton = uibutton(app.PlotTab, 'push');
            app.ZenithTroposphericDelayButton.ButtonPushedFcn = createCallbackFcn(app, @ZenithTroposphericDelayButtonPushed, true);
            app.ZenithTroposphericDelayButton.Text = 'Zenith Tropospheric Delay';
            app.SatelliteNumbersButton = uibutton(app.PlotTab, 'push');
            app.SatelliteNumbersButton.ButtonPushedFcn = createCallbackFcn(app, @SatelliteNumbersButtonPushed, true);
            app.SatelliteNumbersButton.Text = 'Satellite Numbers';
            app.SatelliteVisibilityChartButton = uibutton(app.PlotTab, 'push');
            app.SatelliteVisibilityChartButton.ButtonPushedFcn = createCallbackFcn(app, @SatelliteVisibilityChartButtonPushed, true);
            app.SatelliteVisibilityChartButton.Text = 'Satellite Visibility Chart';
            app.CN0AnalysisButton = uibutton(app.PlotTab, 'push');
            app.CN0AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @CN0AnalysisButtonPushed, true);
            app.CN0AnalysisButton.Text = 'C/N0 Analysis';
            app.GDOPandPDOPValuesButton = uibutton(app.PlotTab, 'push');
            app.GDOPandPDOPValuesButton.ButtonPushedFcn = createCallbackFcn(app, @GDOPandPDOPValuesButtonPushed, true);
            app.GDOPandPDOPValuesButton.Text = 'GDOP and PDOP Values';
            app.ResidualAnalysisPanel = uipanel(app.PlotTab);
            app.ResidualAnalysisPanel.Title = 'Residual Analysis';
            app.Residuals = uibuttongroup(app.ResidualAnalysisPanel);
            app.Residuals.SelectionChangedFcn = createCallbackFcn(app, @ResidualsSelectionChanged, true);
            app.CodeResidualsButton = uiradiobutton(app.Residuals);
            app.CodeResidualsButton.Text = 'Code Residuals';
            app.CodeResidualsButton.Value = true;
            app.PhaseResidualsButton = uiradiobutton(app.Residuals);
            app.PhaseResidualsButton.Text = 'Phase Residuals';
            app.ResidualsforeachsatelliteButton = uiradiobutton(app.Residuals);
            app.ResidualsforeachsatelliteButton.Text = 'Residuals for each satellite';
            app.PLOTButton = uibutton(app.ResidualAnalysisPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.AcctoListBoxLabel = uilabel(app.ResidualAnalysisPanel);
            app.AcctoListBoxLabel.Text = 'Acc. to';
            app.AcctoListBox = uilistbox(app.ResidualAnalysisPanel);
            app.AcctoListBox.Items = {'Elevation Angle', 'C/N0-freq.1', 'C/N0-freq.2'};
            app.AcctoListBox.Value = 'Elevation Angle';
            app.SystemListBoxLabel = uilabel(app.ResidualAnalysisPanel);
            app.SystemListBoxLabel.Text = 'System';
            app.SystemListBox = uilistbox(app.ResidualAnalysisPanel);
            app.SystemListBox.Items = {'GPS', 'GLONASS', 'Galileo', 'BDS-2', 'BDS-3', 'QZSS'};
            app.SystemListBox.Value = 'GPS';
            app.PRNEditFieldLabel = uilabel(app.ResidualAnalysisPanel);
            app.PRNEditFieldLabel.Text = 'PRN';
            app.PRNEditField = uieditfield(app.ResidualAnalysisPanel, 'numeric');
            app.ObservationListBoxLabel = uilabel(app.ResidualAnalysisPanel);
            app.ObservationListBoxLabel.Text = 'Observation';
            app.ObservationListBox = uilistbox(app.ResidualAnalysisPanel);
            app.ObservationListBox.Items = {'Code', 'Phase'};
            app.ObservationListBox.Value = 'Code';
            app.SkyPlotAnalysisButton = uibutton(app.PlotTab, 'push');
            app.SkyPlotAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SkyPlotAnalysisButtonPushed, true);
            app.SkyPlotAnalysisButton.Text = 'SkyPlot Analysis';
            app.HorizontalErrorsButton = uibutton(app.PlotTab, 'push');
            app.HorizontalErrorsButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalErrorsButtonPushed, true);
            app.HorizontalErrorsButton.Text = 'Horizontal Errors';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Icon = fullfile(pathToMLAPP, 'Software_Icons', 'start.png');
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APAS_TR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end